classdef Rocket_Designer_Main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        AutomaticdVCalculationCheck   matlab.ui.control.CheckBox
        SolidLabelStage1              matlab.ui.control.Label
        InfillFactorStage1            matlab.ui.control.Spinner
        ErrorOutput                   matlab.ui.control.Label
        ErrorGlobal                   matlab.ui.control.Label
        TotalLength                   matlab.ui.control.NumericEditField
        TotalMasstLabel_3             matlab.ui.control.Label
        TotalMass                     matlab.ui.control.NumericEditField
        TotalMasstLabel_2             matlab.ui.control.Label
        BoostersTabPanel              matlab.ui.container.Panel
        dVPanel                       matlab.ui.container.Panel
        ThisPercentagewillactasthedVofthefirstPhasewhilethepercentageofeachstagewillactasthedVofthenextphasesLabel  matlab.ui.control.Label
        dVPercentBooster              matlab.ui.control.Spinner
        SolidFuelPanel                matlab.ui.container.Panel
        InfillFactorBooster           matlab.ui.control.Spinner
        FiLabel                       matlab.ui.control.Label
        LiquidFuelPanel               matlab.ui.container.Panel
        FuelTypeBoosters              matlab.ui.control.DropDown
        FuelDropDown_4Label_2         matlab.ui.control.Label
        BoosterFuelSwitch             matlab.ui.control.Switch
        ThrustBooster                 matlab.ui.control.Spinner
        BoosterThrustkNLabel          matlab.ui.control.Label
        DBoosters                     matlab.ui.control.Spinner
        DiametermSpinnerLabel         matlab.ui.control.Label
        TotalMassBoosters             matlab.ui.control.Spinner
        TotalMasstLabel               matlab.ui.control.Label
        MsMeBoosters                  matlab.ui.control.Spinner
        MsMeSpinnerLabel              matlab.ui.control.Label
        IspBoosters                   matlab.ui.control.Spinner
        IspBoosterssSpinnerLabel      matlab.ui.control.Label
        NbBoosters                    matlab.ui.control.Spinner
        NumberofBoostersSpinnerLabel  matlab.ui.control.Label
        Stage1Panel                   matlab.ui.container.Panel
        dVLabel                       matlab.ui.control.Label
        dVPercentStage1               matlab.ui.control.Spinner
        ThrustStage1                  matlab.ui.control.Spinner
        DiametermSpinner_2Label_9     matlab.ui.control.Label
        FuelTypeStage1                matlab.ui.control.DropDown
        FuelDropDownLabel             matlab.ui.control.Label
        DStage1                       matlab.ui.control.Spinner
        DiametermSpinner_2Label_8     matlab.ui.control.Label
        IspStage1                     matlab.ui.control.Spinner
        IspsSpinnerLabel              matlab.ui.control.Label
        MsMeStage1                    matlab.ui.control.Spinner
        MsMeSpinner_2Label            matlab.ui.control.Label
        Stage2Panel                   matlab.ui.container.Panel
        dVLabel_2                     matlab.ui.control.Label
        dVPercentStage2               matlab.ui.control.Spinner
        SolidLabelStage2              matlab.ui.control.Label
        InfillFactorStage2            matlab.ui.control.Spinner
        FuelTypeStage2                matlab.ui.control.DropDown
        FuelDropDownLabel_2           matlab.ui.control.Label
        ThrustStage2                  matlab.ui.control.Spinner
        DiametermSpinner_2Label_7     matlab.ui.control.Label
        DiametermSpinner_3Label       matlab.ui.control.Label
        DStage2                       matlab.ui.control.Spinner
        IspsSpinner_2Label            matlab.ui.control.Label
        IspStage2                     matlab.ui.control.Spinner
        MsMeStage2                    matlab.ui.control.Spinner
        MsMeSpinner_3Label            matlab.ui.control.Label
        Stage3Panel                   matlab.ui.container.Panel
        dVLabel_3                     matlab.ui.control.Label
        dVPercentStage3               matlab.ui.control.Spinner
        SolidLabelStage3              matlab.ui.control.Label
        InfillFactorStage3            matlab.ui.control.Spinner
        ThrustStage3                  matlab.ui.control.Spinner
        DiametermSpinner_2Label_6     matlab.ui.control.Label
        FuelTypeStage3                matlab.ui.control.DropDown
        FuelDropDown_3Label           matlab.ui.control.Label
        DiametermSpinner_4Label       matlab.ui.control.Label
        DStage3                       matlab.ui.control.Spinner
        IspsSpinner_3Label            matlab.ui.control.Label
        IspStage3                     matlab.ui.control.Spinner
        MsMeStage3                    matlab.ui.control.Spinner
        MsMeSpinner_4Label            matlab.ui.control.Label
        Stage4Panel                   matlab.ui.container.Panel
        dVLabel_4                     matlab.ui.control.Label
        dVPercentStage4               matlab.ui.control.Spinner
        SolidLabelStage4              matlab.ui.control.Label
        InfillFactorStage4            matlab.ui.control.Spinner
        ThrustStage4                  matlab.ui.control.Spinner
        DiametermSpinner_2Label_5     matlab.ui.control.Label
        FuelTypeStage4                matlab.ui.control.DropDown
        FuelDropDown_4Label           matlab.ui.control.Label
        DiametermSpinner_5Label       matlab.ui.control.Label
        DStage4                       matlab.ui.control.Spinner
        IspsSpinner_4Label            matlab.ui.control.Label
        IspStage4                     matlab.ui.control.Spinner
        MsMeStage4                    matlab.ui.control.Spinner
        MsMeSpinner_5Label            matlab.ui.control.Label
        DFairing                      matlab.ui.control.Spinner
        FairingsLengthkgLabel_2       matlab.ui.control.Label
        LFairing                      matlab.ui.control.Spinner
        FairingsLengthkgLabel         matlab.ui.control.Label
        BoostersCheckBox              matlab.ui.control.CheckBox
        OutputRocket                  matlab.ui.control.StateButton
        NbStages                      matlab.ui.control.Spinner
        NumberofStagesSpinnerLabel    matlab.ui.control.Label
        InputdV                       matlab.ui.control.StateButton
        dVMission                     matlab.ui.control.Spinner
        dVMissionkmsSpinnerLabel      matlab.ui.control.Label
        MPayload                      matlab.ui.control.Spinner
        MassPayloadkgSpinnerLabel     matlab.ui.control.Label
        Title                         matlab.ui.control.Label
        PlotMass                      matlab.ui.control.UIAxes
        PlotTW                        matlab.ui.control.UIAxes
        PlotTopView                   matlab.ui.control.UIAxes
        PlotSideView                  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        M_Payload = 0; % kg
        dV = 0; % m/s
        nb_Stages = 0;
        Ms_Me = NaN;
        Isp = NaN; % s
        D = NaN; % m
        Fuel = NaN;
        Thurst_Stage = NaN; % kN
        dV_Percent = NaN; % %

        L_Fairing = 0; % m
        D_Fairing = 0; % m
        M_Fairing = 0; % kg

        nb_Boosters = 0;
        Isp_Boosters = 0; % s
        Ms_Me_Boosters = 0;
        Mass_Boosters = 0; % kg
        D_Boosters = 0; % m
        Thurst_Booster = 0; % kN
        dV_Percent_Booster = 0; % %

        M_ergol = NaN; % kg
        M_structure = NaN; % kg
        M_initial = NaN; % kg
        M_final = NaN; % kg
        M_total = 0; % kg
        L_Rocket = 0; % kg

        Solution_found = true; % true if found, otherwise false
    end
    
    methods (Access = private)
        %% Update Values
        function State = update_Values_Stages(app)
            app.ErrorOutput.Visible = "off";
            app.Ms_Me = zeros(1,app.NbStages.Value);
            app.Isp = zeros(1,app.NbStages.Value);
            app.D = zeros(1,app.NbStages.Value);
            app.Fuel = strings(1,app.NbStages.Value);
            app.dV_Percent = zeros(1,app.NbStages.Value);
            for i = 1:app.nb_Stages
                if i == 1
                    app.Ms_Me(i) = app.MsMeStage1.Value;
                    app.Isp(i) = app.IspStage1.Value;
                    app.D(i) = app.DStage1.Value;
                    app.Fuel(i) = app.FuelTypeStage1.Value;
                    app.Thurst_Stage(i) = app.ThrustStage1.Value;
                    app.dV_Percent(i) = app.dVPercentStage1.Value;
                end
                if i == 2
                    app.Ms_Me(i) = app.MsMeStage2.Value;
                    app.Isp(i) = app.IspStage2.Value;
                    app.D(i) = app.DStage2.Value;
                    app.Fuel(i) = app.FuelTypeStage2.Value;
                    app.Thurst_Stage(i) = app.ThrustStage2.Value;
                    app.dV_Percent(i) = app.dVPercentStage2.Value;
                end
                if i == 3
                    app.Ms_Me(i) = app.MsMeStage3.Value;
                    app.Isp(i) = app.IspStage3.Value;
                    app.D(i) = app.DStage3.Value;
                    app.Fuel(i) = app.FuelTypeStage3.Value;
                    app.Thurst_Stage(i) = app.ThrustStage3.Value;
                    app.dV_Percent(i) = app.dVPercentStage3.Value;
                end
                if i == 4
                    app.Ms_Me(i) = app.MsMeStage4.Value;
                    app.Isp(i) = app.IspStage4.Value;
                    app.D(i) = app.DStage4.Value;
                    app.Fuel(i) = app.FuelTypeStage4.Value;
                    app.Thurst_Stage(i) = app.ThrustStage4.Value;
                    app.dV_Percent(i) = app.dVPercentStage4.Value;
                end
            end
            State = 1;
        end

        function State = update_Values(app)
            app.M_Payload = app.MPayload.Value;
            app.dV = app.dVMission.Value*1000;
            app.nb_Stages = app.NbStages.Value;

            app.L_Fairing = app.LFairing.Value;
            app.D_Fairing = app.DFairing.Value;
            app.M_Fairing = 10*pi*app.D_Fairing*sqrt((app.D_Fairing/2)^2+app.L_Fairing^2)*1.5; %Surface d'un cône x 1.5

            update_Values_Stages(app);

            if app.BoostersCheckBox.Value == 1
                app.nb_Boosters = app.NbBoosters.Value;
                app.Isp_Boosters = app.IspBoosters.Value;
                app.Ms_Me_Boosters = app.MsMeBoosters.Value;
                app.Mass_Boosters = app.TotalMassBoosters.Value*1000;
                app.D_Boosters = app.DBoosters.Value;
                app.Thurst_Booster = app.ThrustBooster.Value;
                app.dV_Percent_Booster = app.dVPercentBooster.Value;
            else
                app.nb_Boosters = 0;
            end
            
            if app.AutomaticdVCalculationCheck.Value == 1
                optimization_algorithm(app);
            else
                update_dVPercent(app);
                Manual_dV_Calculation(app);
            end
            

            if app.M_total == 0 || min(app.M_ergol)<=0
                app.ErrorGlobal.Visible = "on";
                cla(app.PlotSideView);
                cla(app.PlotTopView);
                cla(app.PlotTW);
                cla(app.PlotMass);
                app.Solution_found = false;
            else
                app.ErrorGlobal.Visible = "off";
                app.Solution_found = true;
                update_Plots(app);
            end

                
            State = 1;
        end

        function data = update_boosters(app)
            if app.BoostersCheckBox.Value == 1
                data.ISP = (app.Thurst_Stage(1)+app.nb_Boosters*app.Thurst_Booster)/(app.Thurst_Stage(1)/app.Isp(1) + app.nb_Boosters*app.Thurst_Booster/app.Isp_Boosters);
                data.Thrust = app.Thurst_Stage(1)+app.nb_Boosters*app.Thurst_Booster;
                data.IC = (app.Ms_Me(1) + app.nb_Boosters*app.Ms_Me_Boosters)/(app.nb_Boosters+1);
            end
            data.State = 1;
        end

        function State = update_dVPercent(app)
            if app.nb_Boosters == 0
                if sum(app.dV_Percent)~=1
                    app.dV_Percent = app.dV_Percent/sum(app.dV_Percent);
                end
            else
                vect = [app.dV_Percent,app.dV_Percent_Booster];
                if sum(vect)~=1
                    app.dV_Percent = app.dV_Percent/sum(vect);
                    app.dV_Percent_Booster = app.dV_Percent_Booster/sum(vect);
                end
                app.dVPercentBooster.Value = app.dV_Percent_Booster;
            end
            for i=1:app.nb_Stages
                    if i == 1
                        app.dVPercentStage1.Value = app.dV_Percent(1);
                    end
                    if i == 2
                        app.dVPercentStage2.Value = app.dV_Percent(2);
                    end
                    if i == 3
                        app.dVPercentStage3.Value = app.dV_Percent(3);
                    end
                    if i == 4
                        app.dVPercentStage4.Value = app.dV_Percent(4);
                    end
            end
            State = 0;
        end

        function State = upscale_dVPercent(app, idx)
            if app.nb_Boosters == 0
                Percent = app.dV_Percent;
            else
                Percent = [app.dV_Percent_Booster, app.dV_Percent];
                idx = idx + 1;
            end
            N = length(Percent); % Nombre total d'éléments gérés
            min_val = 0.01;      % Seuil minimum garanti (1%)
            % 2. Algorithme de répartition avec plancher de sécurité à 0.01
            if idx < N
                % --- CAS 1 : Modification d'un élément intermédiaire ---
                sum_prior = sum(Percent(1:idx-1)); 
                nb_after = N - idx;
                
                % Borne maximale autorisée pour l'élément actuel afin de laisser 0.01 aux suivants
                max_allowed = 1 - sum_prior - (nb_after * min_val);
                
                % Clamping de la valeur entrée par l'utilisateur
                if Percent(idx) > max_allowed, Percent(idx) = max_allowed; end
                if Percent(idx) < min_val, Percent(idx) = min_val; end
                
                % Calcul du reste à distribuer sur les éléments SUIVANTS
                rem = 1 - (sum_prior + Percent(idx));
                rem_shift = rem - (nb_after * min_val); % Reste après avoir réservé le minimum syndical
                
                % Distribution proportionnelle décalée
                shifted_after = Percent(idx+1:end) - min_val;
                sum_shifted_after = sum(shifted_after);
                
                if sum_shifted_after > 0
                    Percent(idx+1:end) = min_val + shifted_after * (rem_shift / sum_shifted_after);
                else
                    % Si tout le monde était déjà au minimum, on distribue équitablement le bonus
                    Percent(idx+1:end) = min_val + (rem_shift / nb_after);
                end
                
            else
                % --- CAS 2 : Modification du DERNIER élément ---
                nb_before = N - 1;
                max_allowed = 1 - (nb_before * min_val);
                
                % Clamping du dernier élément
                if Percent(idx) > max_allowed, Percent(idx) = max_allowed; end
                if Percent(idx) < min_val, Percent(idx) = min_val; end
                
                % Calcul du reste à distribuer sur les éléments PRÉCÉDENTS
                rem = 1 - Percent(idx);
                rem_shift = rem - (nb_before * min_val);
                
                % Distribution proportionnelle décalée
                shifted_before = Percent(1:N-1) - min_val;
                sum_shifted_before = sum(shifted_before);
                
                if sum_shifted_before > 0
                    Percent(1:N-1) = min_val + shifted_before * (rem_shift / sum_shifted_before);
                else
                    Percent(1:N-1) = min_val + (rem_shift / nb_before);
                end
            end

            for i=1:length(Percent)
                if app.nb_Boosters == 0
                    if i == (1)
                        app.dVPercentStage1.Value = Percent(1);
                    end
                    if i == (2)
                        app.dVPercentStage2.Value = Percent(2);
                    end
                    if i == (3)
                        app.dVPercentStage3.Value = Percent(3);
                    end
                    if i == (4)
                        app.dVPercentStage4.Value = Percent(4);
                    end
                else
                    app.dVPercentBooster.Value = Percent(1);
                    if i == (2)
                        app.dVPercentStage1.Value = Percent(2);
                    end
                    if i == (3)
                        app.dVPercentStage2.Value = Percent(3);
                    end
                    if i == (4)
                        app.dVPercentStage3.Value = Percent(4);
                    end
                    if i == (5)
                        app.dVPercentStage4.Value = Percent(5);
                    end
                end
            end
            State = 1;
        end

        %% Opitimization Algorithm

        function State = optimization_algorithm(app)
            if app.nb_Stages==1 && app.nb_Boosters==0
                c=app.Isp*9.81;
                Mu = exp(app.dV/c);
                Me = (app.M_Payload+app.M_Fairing)*(Mu-1)/(app.Ms_Me+1-app.Ms_Me*Mu);
                Ms = app.Ms_Me * Me;
                Mi = Me  + Ms + app.M_Payload+app.M_Fairing;
                Mf = Mi - Me;

                app.M_ergol = Me;
                app.M_structure = Ms;
                check_negative_Mass(app);
                if any(isnan(app.M_ergol)) || any(isinf(app.M_ergol)) || ~isreal(app.M_ergol)
                    Mi = 0;
                    Mf = 0;
                    app.M_ergol = 0;
                    app.M_structure = 0;
                elseif Me == 0
                    Mi = 0;
                    Mf = 0;
                    app.M_ergol = 0;
                    app.M_structure = 0;
                end
                app.M_initial = Mi;
                app.M_final = Mf;
                app.M_total = Mi;
                app.TotalMass.Value = Mi;
            else
                if app.nb_Boosters==0
                    N = app.nb_Stages;
                    Ic = zeros(1,N);
                    for i=1:N
                        Ic(i) = app.Ms_Me(i);
                    end
                    c = app.Isp*9.81;
                else
                    data = update_boosters(app);
                    N = app.nb_Stages+1;
                    Ic = zeros(1,N);
                    c = zeros(1,N);
    
                    Ic(1) = data.IC;
                    c(1) = data.ISP*9.81;
                    for i=2:N
                        Ic(i) = app.Ms_Me(i-1);
                        c(i) = app.Isp(i-1)*9.81;
                    end
                end
                
                Me = zeros(1,N);
                Ms = zeros(1,N);
                Mi = zeros(1,N);
                Mf = zeros(1,N);
                Mtj = -5;
                Mt = 0;
                
                j = 0;
                while j < 50 
                    if abs(Mt-Mtj)<10^-5
                        break;
                    end
                    Mtj = Mt;
                    KHigh = min(c) - 1e-5; 
                    KLow = -10000;
                
                    Omega = zeros(1,N);
                    for i=1:N
                        Omega(i)=Ic(i)/(1+Ic(i));
                    end
    
                    Vc_K = @(K) sum(arrayfun(@(ci,Oi)( ci * log( (1 - K/ci) / Oi ) ), c, Omega));
    
                    i = 0;
                    K = 0;
    
                    while i < 50
                        KMid = (KLow + KHigh)/2;
                        fMid = Vc_K(KMid) - app.dV;
                        if abs(fMid) < 10^(-10)
                            K = KMid;
                            break;
                        end
                        if fMid <= 0
                            KHigh = KMid;
                            fHigh = fMid;
                        else
                            KLow = KMid;
                            fLow = fMid;
                        end
                        i = i + 1;
                    end
                    id = i;
                    
                    % Calcul Mu, Vetage
                    Mu = zeros(1,N);
                    Vetage = zeros(1,N);
                    
                    for i = 1:N
                        Mu(i) = (1 - K / c(i)) / Omega(i);
                        Vetage(i) = c(i) * log(Mu(i));
                    end
                    
                    
                    % Calcul Masses
                    for i = N:-1:1
                        if app.nb_Boosters == 0
                            if N == 1
                                Me(i) = (Mu(i)-1)*(app.M_Payload+app.M_Fairing)/(app.Ms_Me(i)*(1-Mu(i))+1);
                                Ms(i) = app.Ms_Me(i)*Me(i);
        
                                Mi(i) = app.M_Payload + app.M_Fairing + Ms(i) + Me(i);
                                Mf(i) = Mi(i)-Me(i);
                            elseif  i == N 
                                Me(i) = (Mu(i)-1)*app.M_Payload/(app.Ms_Me(i)*(1-Mu(i))+1);
                                Ms(i) = app.Ms_Me(i)*Me(i);
        
                                Mi(i) = app.M_Payload + Ms(i) + Me(i);
                                Mf(i) = Mi(i)-Me(i);
                            elseif i == N-1
                                Me(i) = ((Mu(i)-1)*(app.M_Fairing + Mi(i+1)))/(app.Ms_Me(i)*(1-Mu(i))+1);
                                Ms(i) = app.Ms_Me(i)*Me(i);
        
                                Mi(i) = app.M_Fairing + Ms(i) + Me(i) + Mi(i+1);
                                Mf(i) = Mi(i) - Me(i);
                            else 
                                Me(i) = ((Mu(i)-1)*Mi(i+1))/(app.Ms_Me(i)*(1-Mu(i))+1);
                                Ms(i) = app.Ms_Me(i)*Me(i);
        
                                Mi(i) = Mi(i+1) + Ms(i) + Me(i);
                                Mf(i) = Mi(i) - Me(i);
                            end
                        else
                            if  i == N 
                                Me(i) = (Mu(i)-1)*app.M_Payload/(Ic(i)*(1-Mu(i))+1);
                                Ms(i) = Ic(i)*Me(i);
        
                                Mi(i) = app.M_Payload + Ms(i) + Me(i);
                                Mf(i) = Mi(i)-Me(i);
                            elseif i == 1
                                if i == N-1
                                    Madd = app.M_Fairing;
                                else
                                    Madd = 0;
                                end
                                Block1 = app.Mass_Boosters*app.nb_Boosters/(1+1/app.Ms_Me_Boosters) + Madd;
                                Block2 = app.Mass_Boosters*app.nb_Boosters + Madd;
                                Me(i) = (Mu(i)*Mi(i+1)+Mu(i)*Block1 - Mi(i+1) - Block2)/(1+app.Ms_Me(i)-app.Ms_Me(i)*Mu(i));
                                Ms(i) = app.Ms_Me(i)*Me(i);
        
                                Mi(i) = Madd + Ms(i) + Me(i) + Mi(i+1) + app.Mass_Boosters*app.nb_Boosters;
                                Mf(i) = Mi(i) - Me(i) - app.Mass_Boosters*app.nb_Boosters/(1+app.Ms_Me_Boosters);
                            elseif i == N-1
                                Me(i) = ((Mu(i)-1)*(app.M_Fairing + Mi(i+1)))/(Ic(i)*(1-Mu(i))+1);
                                Ms(i) = Ic(i)*Me(i);
        
                                Mi(i) = app.M_Fairing + Ms(i) + Me(i) + Mi(i+1);
                                Mf(i) = Mi(i) - Me(i);
                            else 
                                Me(i) = ((Mu(i)-1)*Mi(i+1))/(Ic(i)*(1-Mu(i))+1);
                                Ms(i) = Ic(i)*Me(i);
        
                                Mi(i) = Mi(i+1) + Ms(i) + Me(i);
                                Mf(i) = Mi(i) - Me(i);
                            end
    
                            M = Ms(1) + Ms(2) + Me(1) + Me(2);
                            Ic(1)=(app.Ms_Me(1)*M + app.nb_Boosters*app.Ms_Me_Boosters*app.Mass_Boosters)/(app.nb_Boosters*app.Mass_Boosters + M);
                        end
                    end
                    
                
                    Mt = Mi(1);
                    
                    j = j+1;
                end
    
                if app.nb_Boosters==0
                    app.M_ergol = Me;
                    app.M_structure = Ms;
                else
                    Me_new = zeros(1,app.nb_Stages);
                    Ms_new = zeros(1,app.nb_Stages);
    
                    M = Ms(1) + Ms(2) + Me(1) + Me(2);
                    Me_new(1) = M/(1+app.Ms_Me(1));
                    Ms_new(1) = M - Me_new(1);
                    if app.nb_Stages>1
                        Me_new(2:end) = Me(3:end);
                        Ms_new(2:end) = Ms(3:end);
                    end
    
                    app.M_ergol = Me_new;
                    app.M_structure = Ms_new;
                end
                check_negative_Mass(app);
                if any(isnan(app.M_ergol)) || any(isinf(app.M_ergol)) || ~isreal(app.M_ergol)
                    Mt = 0;
                    for i = 1:app.nb_Stages
                        Mi(i) = 0;
                        Mf(i) = 0;
                        Me(i) = 0;
                        Ms(i) = 0;
                    end
                elseif min(app.M_ergol) <= 0
                    Mt = 0;
                    for i = 1:app.nb_Stages
                        Mi(i) = 0;
                        Mf(i) = 0;
                        Me(i) = 0;
                        Ms(i) = 0;
                    end
                end
                app.M_initial = Mi;
                app.M_final = Mf;
    
                app.M_total = Mt;
                app.TotalMass.Value = Mt/1000;
            end

            State = 1;
        end

        function State = Manual_dV_Calculation(app)
            if app.nb_Stages==1 && app.nb_Boosters==0
                c=app.Isp*9.81;
                Mu = exp(app.dV/c);
                Me = (app.M_Payload+app.M_Fairing)*(Mu-1)/(app.Ms_Me+1-app.Ms_Me*Mu);
                Ms = app.Ms_Me * Me;
                Mi = Me  + Ms + app.M_Payload+app.M_Fairing;
                Mf = Mi - Me;
                app.M_ergol = Me;
                app.M_structure = Ms;
                check_negative_Mass(app);
                
                if any(isnan(app.M_ergol)) || any(isinf(app.M_ergol)) || ~isreal(app.M_ergol)
                    Mi = 0; Mf = 0; app.M_ergol = 0; app.M_structure = 0;
                elseif Me == 0
                    Mi = 0; Mf = 0; app.M_ergol = 0; app.M_structure = 0;
                end
                app.M_initial = Mi;
                app.M_final = Mf;
                app.M_total = Mi;
                app.TotalMass.Value = Mi;    
            else
                if app.nb_Boosters==0
                    N = app.nb_Stages;
                    Ic = zeros(1,N);
                    for i=1:N
                        Ic(i) = app.Ms_Me(i);
                    end
                    c = app.Isp*9.81;
                    
                    dVPerc = zeros(1,N);
                    for i = 1:N
                        dVPerc(i) = app.dV_Percent(i);
                    end
                else
                    data = update_boosters(app);
                    N = app.nb_Stages+1;
                    Ic = zeros(1,N);
                    c = zeros(1,N);
        
                    Ic(1) = data.IC;
                    c(1) = data.ISP*9.81;
                    for i=2:N
                        Ic(i) = app.Ms_Me(i-1);
                        c(i) = app.Isp(i-1)*9.81;
                    end
                    dVPerc = zeros(1,N);
                    dVPerc(1) = app.dV_Percent_Booster;
                    for i = 2:N
                        dVPerc(i) = app.dV_Percent(i-1);
                    end
                end
                
                Me = zeros(1,N);
                Ms = zeros(1,N);
                Mi = zeros(1,N);
                Mf = zeros(1,N);
                Vetage = zeros(1,N);
                Mu = zeros(1,N);
                Mt = 0;
                
                for i = 1:N
                    Vetage(i) = dVPerc(i)*app.dV;
                    Mu(i) = exp(Vetage(i)/c(i));
                end
                    
                % Calcul des Masses par étage
                for i = N:-1:1
                     if app.nb_Boosters == 0
                         if N == 1
                              Me(i) = (Mu(i)-1)*(app.M_Payload+app.M_Fairing)/(app.Ms_Me(i)*(1-Mu(i))+1);
                              Ms(i) = app.Ms_Me(i)*Me(i);
        
                              Mi(i) = app.M_Payload + app.M_Fairing + Ms(i) + Me(i);
                              Mf(i) = Mi(i)-Me(i);
                          elseif  i == N 
                              Me(i) = (Mu(i)-1)*app.M_Payload/(app.Ms_Me(i)*(1-Mu(i))+1);
                              Ms(i) = app.Ms_Me(i)*Me(i);
        
                              Mi(i) = app.M_Payload + Ms(i) + Me(i);
                              Mf(i) = Mi(i)-Me(i);
                          elseif i == N-1
                              Me(i) = ((Mu(i)-1)*(app.M_Fairing + Mi(i+1)))/(app.Ms_Me(i)*(1-Mu(i))+1);
                              Ms(i) = app.Ms_Me(i)*Me(i);
        
                              Mi(i) = app.M_Fairing + Ms(i) + Me(i) + Mi(i+1);
                              Mf(i) = Mi(i) - Me(i);
                          else 
                              Me(i) = ((Mu(i)-1)*Mi(i+1))/(app.Ms_Me(i)*(1-Mu(i))+1);
                              Ms(i) = app.Ms_Me(i)*Me(i);
        
                              Mi(i) = Mi(i+1) + Ms(i) + Me(i);
                              Mf(i) = Mi(i) - Me(i);
                          end
                     else
                          if  i == N 
                              Me(i) = (Mu(i)-1)*app.M_Payload/(Ic(i)*(1-Mu(i))+1);
                              Ms(i) = Ic(i)*Me(i);
        
                              Mi(i) = app.M_Payload + Ms(i) + Me(i);
                              Mf(i) = Mi(i)-Me(i);
                           elseif i == 1
                              if i == N-1
                                  Madd = app.M_Fairing;
                              else
                                  Madd = 0;
                              end
                              Block1 = app.Mass_Boosters*app.nb_Boosters/(1+1/app.Ms_Me_Boosters) + Madd;
                              Block2 = app.Mass_Boosters*app.nb_Boosters + Madd;
                              Me(i) = (Mu(i)*Mi(i+1)+Mu(i)*Block1 - Mi(i+1) - Block2)/(1+app.Ms_Me(i)-app.Ms_Me(i)*Mu(i));
                              Ms(i) = app.Ms_Me(i)*Me(i);
        
                              Mi(i) = Madd + Ms(i) + Me(i) + Mi(i+1) + app.Mass_Boosters*app.nb_Boosters;
                              Mf(i) = Mi(i) - Me(i) - app.Mass_Boosters*app.nb_Boosters/(1+app.Ms_Me_Boosters);
                          elseif i == N-1
                              Me(i) = ((Mu(i)-1)*(app.M_Fairing + Mi(i+1)))/(Ic(i)*(1-Mu(i))+1);
                              Ms(i) = Ic(i)*Me(i);
        
                              Mi(i) = app.M_Fairing + Ms(i) + Me(i) + Mi(i+1);
                              Mf(i) = Mi(i) - Me(i);
                          else 
                              Me(i) = ((Mu(i)-1)*Mi(i+1))/(Ic(i)*(1-Mu(i))+1);
                              Ms(i) = Ic(i)*Me(i);
        
                              Mi(i) = Mi(i+1) + Ms(i) + Me(i);
                              Mf(i) = Mi(i) - Me(i);
                          end
        
                          M = Ms(1) + Ms(2) + Me(1) + Me(2);
                          Ic(1)=(app.Ms_Me(1)*M + app.nb_Boosters*app.Ms_Me_Boosters*app.Mass_Boosters)/(app.nb_Boosters*app.Mass_Boosters + M);
                     end
                end
                    
                Mt = Mi(1);
                    
                if app.nb_Boosters==0
                     app.M_ergol = Me;
                     app.M_structure = Ms;
                else
                     Me_new = zeros(1,app.nb_Stages);
                     Ms_new = zeros(1,app.nb_Stages);
        
                     M = Ms(1) + Ms(2) + Me(1) + Me(2);
                     Me_new(1) = M/(1+app.Ms_Me(1));
                     Ms_new(1) = M - Me_new(1);
                     if app.nb_Stages>1
                         Me_new(2:end) = Me(3:end);
                         Ms_new(2:end) = Ms(3:end);
                     end
        
                     app.M_ergol = Me_new;
                     app.M_structure = Ms_new;
                end
                
                check_negative_Mass(app);
                 
                if any(isnan(app.M_ergol)) || any(isinf(app.M_ergol)) || ~isreal(app.M_ergol)
                    Mt = 0;
                    for i = 1:app.nb_Stages
                        Mi(i) = 0; Mf(i) = 0; Me(i) = 0; Ms(i) = 0;
                    end
                elseif min(app.M_ergol) <= 0
                    Mt = 0;
                    for i = 1:app.nb_Stages
                        Mi(i) = 0; Mf(i) = 0; Me(i) = 0; Ms(i) = 0;
                    end
                end
                 
                app.M_initial = Mi;
                app.M_final = Mf;
                app.M_total = Mt;
                app.TotalMass.Value = Mt/1000;
            end
            
            State = 1;
        end


        %% Calculation of volumes

        function length = calc_length(app)
            % Densités des ergols (en kg/m³)
            density_LH2  = 71;
            density_LCh4 = 423;
            density_LOx  = 1141;
            density_RP1  = 810;
            density_NTO  = 1440;
            density_UDMH = 793;
            density_powder = 1750;
            
            Mix_ratio_LO2_LH2 = 6;
            Mix_ratio_LO2_LCh4 = 3.6;
            Mix_ratio_LO2_Rp1 = 2.5;
            Mix_ratio_NTO_UDMH = 2.0;

            
            Lox = zeros(1, app.nb_Stages);
            Lf = zeros(1, app.nb_Stages);
            for i=1:app.nb_Stages
                switch app.Fuel(i)
                    case 'LOx/LH2'
                        OF = Mix_ratio_LO2_LH2;
                        rho_ox = density_LOx;
                        rho_f = density_LH2;
                    case 'LOx/LCh4'
                        OF = Mix_ratio_LO2_LCh4;
                        rho_ox = density_LOx;
                        rho_f = density_LCh4;
                    case 'LOx/RP1'
                        OF = Mix_ratio_LO2_Rp1;
                        rho_ox = density_LOx;
                        rho_f = density_RP1;
                    case 'NTO/UDMH'
                        OF = Mix_ratio_NTO_UDMH;
                        rho_ox = density_NTO;
                        rho_f = density_UDMH;
                    case 'Solid'
                        switch i
                            case 1
                                Infill = app.InfillFactorStage1.Value;
                            case 2
                                Infill = app.InfillFactorStage2.Value;
                            case 3
                                Infill = app.InfillFactorStage3.Value;
                            case 4
                                Infill = app.InfillFactorStage4.Value;
                        end
                        L = app.M_ergol(i)/(pi*(app.D(i)/2)^2)/Infill/density_powder;
                end
                if strcmp(app.Fuel(i),'Solid')
                    Lox(i) = L;
                    Lf(i) = 0;
                else
                    Mox = app.M_ergol(i)*OF/(1+OF);
                    Mf = app.M_ergol(i) - Mox;
    
                    Vox = Mox/rho_ox;
                    Vf = Mf/rho_f;
                    Lox(i) = (Vox-4/3*pi*(app.D(i)/2)^3)/(pi*(app.D(i)/2)^2);
                    Lf(i) = (Vf-4/3*pi*(app.D(i)/2)^3)/(pi*(app.D(i)/2)^2);
                end

                if Lox(i)<0
                    Lox(i)= - Vox/(4/3*pi*(app.D(i)/2)^2)*2;
                end
                if Lf(i)<0
                    Lf(i)= - Vf/(4/3*pi*(app.D(i)/2)^2)*2;
                end
            end
            length.Lox = Lox;
            length.Lf = Lf;
        end

        function State = check_negative_Mass(app)
            for i = 1:app.nb_Stages
                if app.M_ergol(i) <= 0
                    app.M_ergol(i) = 0;
                    app.M_structure(i) = 0;
                    
                    app.Solution_found = false;
                end
            end
            State = 1;
        end

        function length = calc_length_booster(app)
            % Densités des ergols (en kg/m³)
            density_LH2  = 71;
            density_LCh4 = 423;
            density_LOx  = 1141;
            density_RP1  = 810;
            density_NTO  = 1440;
            density_UDMH = 793;
            density_powder = 1750;
            
            Mix_ratio_LO2_LH2 = 6;
            Mix_ratio_LO2_LCh4 = 3.6;
            Mix_ratio_LO2_Rp1 = 2.5;
            Mix_ratio_NTO_UDMH = 2.0;
            
            Me = app.Mass_Boosters/(1+app.Ms_Me_Boosters);

            if strcmp(app.BoosterFuelSwitch.Value,'Liquid fuel')
                switch app.FuelTypeBoosters.Value
                    case 'LOx/LH2'
                        OF = Mix_ratio_LO2_LCh4;
                        rho_ox = density_LOx;
                        rho_f = density_LH2;
                    case 'LOx/LCh4'
                        OF = Mix_ratio_LO2_LH2;
                        rho_ox = density_LOx;
                        rho_f = density_LCh4;
                    case 'LOx/RP1'
                        OF = Mix_ratio_LO2_Rp1;
                        rho_ox = density_LOx;
                        rho_f = density_RP1;
                    case 'NTO/UDMH'
                        OF = Mix_ratio_NTO_UDMH;
                        rho_ox = density_NTO;
                        rho_f = density_UDMH;
                end
                Mox = Me*OF/(1+OF);
                Mf = Me - Mox;

                Vox = Mox/rho_ox;
                Vf = Mf/rho_f;

                Lox = (Vox-4/3*pi*(app.D_Boosters/2)^3)/(pi*(app.D_Boosters/2)^2);
                Lf = (Vf-4/3*pi*(app.D_Boosters/2)^3)/(pi*(app.D_Boosters/2)^2);

                if Lox<0
                    Lox= - Vox/(4/3*pi*(app.D_Boosters/2)^2)*2;
                end
                if Lf<0
                    Lf= - Vf/(4/3*pi*(app.D_Boosters/2)^2)*2;
                end
                
                
                length.Lox = Lox;
                length.Lf = Lf;
            else
                L = Me/(pi*(app.D_Boosters/2)^2)/app.InfillFactorBooster.Value/density_powder;
                length = L;
            end
        end
        %% Update GUI
        function State = update_Plots(app)
            length = calc_length(app);

            semi_circle=0:0.01:pi;
            circle = 0:0.01:2*pi;

            L = 0;
            %%% Side view

            cla(app.PlotSideView)
            axis(app.PlotSideView, "equal")
            hold(app.PlotSideView, 'on')

            for i=1:app.nb_Stages
                if i>1
                    L0 = L +max(app.D(i),app.D(i-1))/min(app.D(i),app.D(i-1));
                    line(app.PlotSideView, [app.D(i-1)/2 app.D(i)/2], [L L+max(app.D(i),app.D(i-1))/min(app.D(i),app.D(i-1))],"Color","Black")
                    line(app.PlotSideView, [-app.D(i-1)/2 -app.D(i)/2], [L L+max(app.D(i),app.D(i-1))/min(app.D(i),app.D(i-1))],"Color","Black")
                    L = L +max(app.D(i),app.D(i-1))/min(app.D(i),app.D(i-1));
                else
                    L0 = 1.5;
                end
                line(app.PlotSideView, [app.D(i)/3 app.D(i)/5], [L L+1.5],"Color","Black")
                line(app.PlotSideView, [-app.D(i)/3 -app.D(i)/5], [L L+1.5],"Color","Black")
                line(app.PlotSideView, [app.D(i)/3 -app.D(i)/3], [L L],"Color","Black")
                L = L + 1.5;

                x=app.D(i)/2*cos(semi_circle);
                y=app.D(i)/2*sin(semi_circle);

                switch app.Fuel(i)
                    case 'LOx/LH2'
                        if length.Lox(i)>0
                            plot(app.PlotSideView,x,L + app.D(i)/2 -y,"Color","Red");
                            L = L + app.D(i)/2;
                            line(app.PlotSideView, [app.D(i)/2 app.D(i)/2], [L L+length.Lox(i)],"Color","Red")
                            line(app.PlotSideView, [-app.D(i)/2 -app.D(i)/2], [L L+length.Lox(i)],"Color","Red")
                            L = L + length.Lox(i);
                            plot(app.PlotSideView,x,L + y,"Color","Red");
                            L = L + app.D(i)/2;
                        else
                            x_ellipse=app.D(i)/2*cos(circle);
                            y_ellipse=-length.Lox(i)/2*sin(circle);
                            L = L - length.Lox(i)/2;
                            plot(app.PlotSideView,x_ellipse,L + y_ellipse,"Color","Red");
                            L = L - length.Lox(i)/2;
                        end

                        if length.Lf(i)>0
                            plot(app.PlotSideView,x,L + app.D(i)/2 -y,"Color","Blue");
                            L = L + app.D(i)/2;
                            line(app.PlotSideView, [app.D(i)/2 app.D(i)/2], [L L+length.Lf(i)],"Color","Blue")
                            line(app.PlotSideView, [-app.D(i)/2 -app.D(i)/2], [L L+length.Lf(i)],"Color","Blue")
                            L = L + length.Lf(i);
                            plot(app.PlotSideView,x,L + y,"Color","Blue");
                            L = L + app.D(i)/2;
                        else
                            x_ellipse=app.D(i)/2*cos(circle);
                            y_ellipse=-length.Lf(i)/2*sin(circle);
                            L = L - length.Lf(i)/2;
                            plot(app.PlotSideView,x_ellipse,L + y_ellipse,"Color","Blue");
                            L = L - length.Lf(i)/2;
                        end
                    case 'LOx/LCh4'
                        if length.Lox(i)>0
                            plot(app.PlotSideView,x,L + app.D(i)/2 -y,"Color","Red");
                            L = L + app.D(i)/2;
                            line(app.PlotSideView, [app.D(i)/2 app.D(i)/2], [L L+length.Lox(i)],"Color","Red")
                            line(app.PlotSideView, [-app.D(i)/2 -app.D(i)/2], [L L+length.Lox(i)],"Color","Red")
                            L = L + length.Lox(i);
                            plot(app.PlotSideView,x,L + y,"Color","Red");
                            L = L + app.D(i)/2;
                        else
                            x_ellipse=app.D(i)/2*cos(circle);
                            y_ellipse=-length.Lox(i)/2*sin(circle);
                            L = L - length.Lox(i)/2;
                            plot(app.PlotSideView,x_ellipse,L + y_ellipse,"Color","Red");
                            L = L - length.Lox(i)/2;
                        end

                        if length.Lf(i)>0
                            plot(app.PlotSideView,x,L + app.D(i)/2 -y,"Color",[0.8 0.8 0.1]);
                            L = L + app.D(i)/2;
                            line(app.PlotSideView, [app.D(i)/2 app.D(i)/2], [L L+length.Lf(i)],"Color",[0.8 0.8 0.1])
                            line(app.PlotSideView, [-app.D(i)/2 -app.D(i)/2], [L L+length.Lf(i)],"Color",[0.8 0.8 0.1])
                            L = L + length.Lf(i);
                            plot(app.PlotSideView,x,L + y,"Color",[0.8 0.8 0.1]);
                            L = L + app.D(i)/2;
                        else
                            x_ellipse=app.D(i)/2*cos(circle);
                            y_ellipse=-length.Lf(i)/2*sin(circle);
                            L = L - length.Lf(i)/2;
                            plot(app.PlotSideView,x_ellipse,L + y_ellipse,"Color","[0.8 0.8 0.1]");
                            L = L - length.Lf(i)/2;
                        end
                    case 'LOx/RP1'
                        if length.Lf(i)>0
                            plot(app.PlotSideView,x,L + app.D(i)/2 -y,"Color","Black");
                            L = L + app.D(i)/2;
                            line(app.PlotSideView, [app.D(i)/2 app.D(i)/2], [L L+length.Lf(i)],"Color","Black")
                            line(app.PlotSideView, [-app.D(i)/2 -app.D(i)/2], [L L+length.Lf(i)],"Color","Black")
                            L = L + length.Lf(i);
                            plot(app.PlotSideView,x,L + y,"Color","Black");
                            L = L + app.D(i)/2;
                        else
                            x_ellipse=app.D(i)/2*cos(circle);
                            y_ellipse=-length.Lf(i)/2*sin(circle);
                            L = L - length.Lf(i)/2;
                            plot(app.PlotSideView,x_ellipse,L + y_ellipse,"Color","Black");
                            L = L - length.Lf(i)/2;
                        end

                        if length.Lox(i)>0
                            plot(app.PlotSideView,x,L + app.D(i)/2 -y,"Color","Red");
                            L = L + app.D(i)/2;
                            line(app.PlotSideView, [app.D(i)/2 app.D(i)/2], [L L+length.Lox(i)],"Color","Red")
                            line(app.PlotSideView, [-app.D(i)/2 -app.D(i)/2], [L L+length.Lox(i)],"Color","Red")
                            L = L + length.Lox(i);
                            plot(app.PlotSideView,x,L + y,"Color","Red");
                            L = L + app.D(i)/2;
                        else
                            x_ellipse=app.D(i)/2*cos(circle);
                            y_ellipse=-length.Lox(i)/2*sin(circle);
                            L = L - length.Lox(i)/2;
                            plot(app.PlotSideView,x_ellipse,L + y_ellipse,"Color","Red");
                            L = L - length.Lox(i)/2;
                        end
                    case 'NTO/UDMH'
                        if length.Lf(i)>0
                            plot(app.PlotSideView,x,L + app.D(i)/2 -y,"Color",[0.8 0.8 0.1]);
                            L = L + app.D(i)/2;
                            line(app.PlotSideView, [app.D(i)/2 app.D(i)/2], [L L+length.Lf(i)],"Color",[0.8 0.8 0.1])
                            line(app.PlotSideView, [-app.D(i)/2 -app.D(i)/2], [L L+length.Lf(i)],"Color",[0.8 0.8 0.1])
                            L = L + length.Lf(i);
                            plot(app.PlotSideView,x,L + y,"Color",[0.8 0.8 0.1]);
                            L = L + app.D(i)/2;
                        else
                            x_ellipse=app.D(i)/2*cos(circle);
                            y_ellipse=-length.Lf(i)/2*sin(circle);
                            L = L - length.Lf(i)/2;
                            plot(app.PlotSideView,x_ellipse,L + y_ellipse,"Color","[0.8 0.8 0.1]");
                            L = L - length.Lf(i)/2;
                        end

                        if length.Lox(i)>0
                            plot(app.PlotSideView,x,L + app.D(i)/2 -y,"Color","Magenta");
                            L = L + app.D(i)/2;
                            line(app.PlotSideView, [app.D(i)/2 app.D(i)/2], [L L+length.Lox(i)],"Color","Magenta")
                            line(app.PlotSideView, [-app.D(i)/2 -app.D(i)/2], [L L+length.Lox(i)],"Color","Magenta")
                            L = L + length.Lox(i);
                            plot(app.PlotSideView,x,L + y,"Color","Red");
                            L = L + app.D(i)/2;
                        else
                            x_ellipse=app.D(i)/2*cos(circle);
                            y_ellipse=-length.Lox(i)/2*sin(circle);
                            L = L - length.Lox(i)/2;
                            plot(app.PlotSideView,x_ellipse,L + y_ellipse,"Color","Magenta");
                            L = L - length.Lox(i)/2;
                        end
                    case 'Solid'
                        switch i
                            case 1
                                Infill = app.InfillFactorStage1.Value;
                            case 2
                                Infill = app.InfillFactorStage2.Value;
                            case 3
                                Infill = app.InfillFactorStage3.Value;
                            case 4
                                Infill = app.InfillFactorStage4.Value;
                        end
                        if Infill ~= 1
                            line(app.PlotSideView, [app.D(i)/2 app.D(i)/2*(1-Infill)], [L L],"Color","Black")
                            line(app.PlotSideView, [-app.D(i)/2 -app.D(i)/2*(1-Infill)], [L L],"Color","Black")
                            line(app.PlotSideView, [app.D(i)/2 app.D(i)/2*(1-Infill)], [L+length.Lox(i) L+length.Lox(i)],"Color","Black")
                            line(app.PlotSideView, [-app.D(i)/2 -app.D(i)/2*(1-Infill)], [L+length.Lox(i) L+length.Lox(i)],"Color","Black")
                            line(app.PlotSideView, [-app.D(i)/2*(1-Infill) -app.D(i)/2*(1-Infill)], [L L+length.Lox(i)],"Color","Black")
                            line(app.PlotSideView, [app.D(i)/2*(1-Infill) app.D(i)/2*(1-Infill)], [L L+length.Lox(i)],"Color","Black")
                            line(app.PlotSideView, [app.D(i)/2 -app.D(i)/2], [L L],"Color","Black")
                            line(app.PlotSideView, [app.D(i)/2 -app.D(i)/2], [L+length.Lox(i) L+length.Lox(i)],"Color","Black")
                        else
                            line(app.PlotSideView, [app.D(i)/2 -app.D(i)/2], [L L],"Color","Black")
                            line(app.PlotSideView, [app.D(i)/2 -app.D(i)/2], [L+length.Lox(i) L+length.Lox(i)],"Color","Black")
                        end
                        L = L + length.Lox(i);
                end 
                line(app.PlotSideView, [app.D(i)/2 app.D(i)/2], [L0 L],"Color","Black")
                line(app.PlotSideView, [-app.D(i)/2 -app.D(i)/2], [L0 L],"Color","Black")
            end
            ratio_diametres = max(app.D(app.nb_Stages), app.D_Fairing) / min(app.D(app.nb_Stages), app.D_Fairing);
            if ratio_diametres > (app.L_Fairing - 2) % Protection si la coiffe est très courte
                l = app.L_Fairing * 0.2; % La transition prend 20% de la hauteur de la coiffe
            else
                l = min(2, app.L_Fairing * 0.2); % Maximum 2 mètres de transition conique
            end
            line(app.PlotSideView, [app.D(app.nb_Stages)/2 app.D_Fairing/2], [L L+l],"Color","Black")
            line(app.PlotSideView, [-app.D(app.nb_Stages)/2 -app.D_Fairing/2], [L L+l],"Color","Black")

            L = L + l;
            Lf = app.L_Fairing - l;
            line(app.PlotSideView, [app.D_Fairing/2 app.D_Fairing/2], [L L+Lf-5],"Color","Black")
            line(app.PlotSideView, [-app.D_Fairing/2 -app.D_Fairing/2], [L L+Lf-5],"Color","Black")

            line(app.PlotSideView, [-app.D_Fairing/2+0.5*app.D_Fairing/4 app.D_Fairing/2-0.5*app.D_Fairing/4], [L L],"Color","Red")
            line(app.PlotSideView, [-app.D_Fairing/2+0.5*app.D_Fairing/4 -app.D_Fairing/2+0.5*app.D_Fairing/4], [L L+Lf-5],"Color","Red")
            line(app.PlotSideView, [app.D_Fairing/2-0.5*app.D_Fairing/4 app.D_Fairing/2-0.5*app.D_Fairing/4], [L L+Lf-5],"Color","Red")
            line(app.PlotSideView, [-app.D_Fairing/2+0.5*app.D_Fairing/4 app.D_Fairing/2-0.5*app.D_Fairing/4], [L+Lf-5 L+Lf-5],"Color","Red")

            
            x_fairing=app.D_Fairing/2*cos(semi_circle);
            y_fairing=3*sin(semi_circle);
            plot(app.PlotSideView,x_fairing,L+Lf -5 + y_fairing,"Color","Black");
            L = L+app.L_Fairing;
            if any(isnan(L)) || any(isinf(L)) || ~isreal(L)
                L = 0;
            elseif L <= 0
                L = 0;
            end
            app.L_Rocket = L;
            app.TotalLength.Value = L;

            if app.nb_Boosters ~= 0
                length = calc_length_booster(app);
                for i=1:app.nb_Boosters
                    L = 0;
                    y_d = (app.D(1)/2 + app.D_Boosters/2)*cos(2*pi/app.nb_Boosters*i);
                    line(app.PlotSideView, [app.D_Boosters/3+y_d app.D_Boosters/5+y_d], [L L+1.5],"Color","Black")
                    line(app.PlotSideView, [-app.D_Boosters/3+y_d -app.D_Boosters/5+y_d], [L L+1.5],"Color","Black")
                    line(app.PlotSideView, [app.D_Boosters/3+y_d -app.D_Boosters/3+y_d], [L L],"Color","Black")
                    L = L + 1.5;
                    L0 = 1.5;
                    x=app.D_Boosters/2*cos(semi_circle);
                    y=app.D_Boosters/2*sin(semi_circle);
                    if strcmp(app.BoosterFuelSwitch.Value, 'Liquid fuel')
                        switch app.FuelTypeBoosters.Value
                            case 'LOx/LH2'
                                if length.Lox>0
                                    plot(app.PlotSideView,x+y_d,L + app.D_Boosters/2 -y,"Color","Red");
                                    L = L + app.D_Boosters/2;
                                    line(app.PlotSideView, [app.D_Boosters/2+y_d app.D_Boosters/2+y_d], [L L+length.Lox],"Color","Red")
                                    line(app.PlotSideView, [-app.D_Boosters/2+y_d -app.D_Boosters/2+y_d], [L L+length.Lox],"Color","Red")
                                    L = L + length.Lox;
                                    plot(app.PlotSideView,x+y_d,L + y,"Color","Red");
                                    L = L + app.D_Boosters/2;
                                else
                                    x_ellipse=app.D_Boosters/2*cos(circle);
                                    y_ellipse=-length.Lox/2*sin(circle);
                                    L = L - length.Lox/2;
                                    plot(app.PlotSideView,x_ellipse+y_d,L + y_ellipse,"Color","Red");
                                    L = L - length.Lox/2;
                                end
        
                                if length.Lf>0
                                    plot(app.PlotSideView,x+y_d,L + app.D_Boosters/2 -y,"Color","Blue");
                                    L = L + app.D_Boosters/2;
                                    line(app.PlotSideView, [app.D_Boosters/2+y_d app.D_Boosters/2+y_d], [L L+length.Lf],"Color","Blue")
                                    line(app.PlotSideView, [-app.D_Boosters/2+y_d -app.D_Boosters/2+y_d], [L L+length.Lf],"Color","Blue")
                                    L = L + length.Lf;
                                    plot(app.PlotSideView,x+y_d,L + y,"Color","Blue");
                                    L = L + app.D_Boosters/2;
                                else
                                    x_ellipse=app.D_Boosters/2*cos(circle);
                                    y_ellipse=-length.Lf/2*sin(circle);
                                    L = L - length.Lf/2;
                                    plot(app.PlotSideView,x_ellipse+y_d,L + y_ellipse,"Color","Blue");
                                    L = L - length.Lf/2;
                                end
                            case 'LOx/LCh4'
                                if length.Lox>0
                                    plot(app.PlotSideView,x+y_d,L + app.D_Boosters/2 -y,"Color","Red");
                                    L = L + app.D_Boosters/2;
                                    line(app.PlotSideView, [app.D_Boosters/2+y_d app.D_Boosters/2+y_d], [L L+length.Lox],"Color","Red")
                                    line(app.PlotSideView, [-app.D_Boosters/2+y_d -app.D_Boosters/2+y_d], [L L+length.Lox],"Color","Red")
                                    L = L + length.Lox;
                                    plot(app.PlotSideView,x+y_d,L + y,"Color","Red");
                                    L = L + app.D_Boosters/2;
                                else
                                    x_ellipse=app.D_Boosters/2*cos(circle);
                                    y_ellipse=-length.Lox/2*sin(circle);
                                    L = L - length.Lox/2;
                                    plot(app.PlotSideView,x_ellipse+y_d,L + y_ellipse,"Color","Red");
                                    L = L - length.Lox/2;
                                end
        
                                if length.Lf>0
                                    plot(app.PlotSideView,x+y_d,L + app.D_Boosters/2 -y,"Color",[0.8 0.8 0.1]);
                                    L = L + app.D_Boosters/2;
                                    line(app.PlotSideView, [app.D_Boosters/2+y_d app.D_Boosters/2+y_d], [L L+length.Lf],"Color",[0.8 0.8 0.1])
                                    line(app.PlotSideView, [-app.D_Boosters/2+y_d -app.D_Boosters/2+y_d], [L L+length.Lf],"Color",[0.8 0.8 0.1])
                                    L = L + length.Lf;
                                    plot(app.PlotSideView,x+y_d,L + y,"Color",[0.8 0.8 0.1]);
                                    L = L + app.D_Boosters/2;
                                else
                                    x_ellipse=app.D_Boosters/2*cos(circle);
                                    y_ellipse=-length.Lf/2*sin(circle);
                                    L = L - length.Lf/2;
                                    plot(app.PlotSideView,x_ellipse+y_d,L + y_ellipse,"Color","[0.8 0.8 0.1]");
                                    L = L - length.Lf/2;
                                end
                            case 'LOx/RP1'
                                if length.Lf>0
                                    plot(app.PlotSideView,x+y_d,L + app.D_Boosters/2 -y,"Color","Black");
                                    L = L + app.D_Boosters/2;
                                    line(app.PlotSideView, [app.D_Boosters/2+y_d app.D_Boosters/2+y_d], [L L+length.Lf],"Color","Black")
                                    line(app.PlotSideView, [-app.D_Boosters/2+y_d -app.D_Boosters/2+y_d], [L L+length.Lf],"Color","Black")
                                    L = L + length.Lf;
                                    plot(app.PlotSideView,x+y_d,L + y,"Color","Black");
                                    L = L + app.D_Boosters/2;
                                else
                                    x_ellipse=app.D_Boosters/2*cos(circle);
                                    y_ellipse=-length.Lf/2*sin(circle);
                                    L = L - length.Lf/2;
                                    plot(app.PlotSideView,x_ellipse+y_d,L + y_ellipse,"Color","Black");
                                    L = L - length.Lf/2;
                                end
        
                                if length.Lox>0
                                    plot(app.PlotSideView,x+y_d,L + app.D_Boosters/2 -y,"Color","Red");
                                    L = L + app.D_Boosters/2;
                                    line(app.PlotSideView, [app.D_Boosters/2+y_d app.D_Boosters/2+y_d], [L L+length.Lox],"Color","Red")
                                    line(app.PlotSideView, [-app.D_Boosters/2+y_d -app.D_Boosters/2+y_d], [L L+length.Lox],"Color","Red")
                                    L = L + length.Lox;
                                    plot(app.PlotSideView,x+y_d,L + y,"Color","Red");
                                    L = L + app.D_Boosters/2;
                                else
                                    x_ellipse=app.D_Boosters/2*cos(circle);
                                    y_ellipse=-length.Lox/2*sin(circle);
                                    L = L - length.Lox/2;
                                    plot(app.PlotSideView,x_ellipse+y_d,L + y_ellipse,"Color","Red");
                                    L = L - length.Lox/2;
                                end
                            case 'NTO/UDMH'
                                if length.Lf>0
                                    plot(app.PlotSideView,x+y_d,L + app.D_Boosters/2 -y,"Color",[0.8 0.8 0.1]);
                                    L = L + app.D_Boosters/2;
                                    line(app.PlotSideView, [app.D_Boosters/2+y_d app.D_Boosters/2+y_d], [L L+length.Lf],"Color",[0.8 0.8 0.1])
                                    line(app.PlotSideView, [-app.D_Boosters/2+y_d -app.D_Boosters/2+y_d], [L L+length.Lf],"Color",[0.8 0.8 0.1])
                                    L = L + length.Lf;
                                    plot(app.PlotSideView,x+y_d,L + y,"Color",[0.8 0.8 0.1]);
                                    L = L + app.D_Boosters/2;
                                else
                                    x_ellipse=app.D_Boosters/2*cos(circle);
                                    y_ellipse=-length.Lf/2*sin(circle);
                                    L = L - length.Lf/2;
                                    plot(app.PlotSideView,x_ellipse+y_d,L + y_ellipse,"Color","[0.8 0.8 0.1]");
                                    L = L - length.Lf/2;
                                end
        
                                if length.Lox>0
                                    plot(app.PlotSideView,x+y_d,L + app.D_Boosters/2 -y,"Color","Magenta");
                                    L = L + app.D_Boosters/2;
                                    line(app.PlotSideView, [app.D_Boosters/2+y_d app.D_Boosters/2+y_d], [L L+length.Lox],"Color","Magenta")
                                    line(app.PlotSideView, [-app.D_Boosters/2+y_d -app.D_Boosters/2+y_d], [L L+length.Lox],"Color","Magenta")
                                    L = L + length.Lox;
                                    plot(app.PlotSideView,x+y_d,L + y,"Color","Red");
                                    L = L + app.D_Boosters/2;
                                else
                                    x_ellipse=app.D_Boosters/2*cos(circle);
                                    y_ellipse=-length.Lox/2*sin(circle);
                                    L = L - length.Lox/2;
                                    plot(app.PlotSideView,x_ellipse+y_d,L + y_ellipse,"Color","Magenta");
                                    L = L - length.Lox/2;
                                end
                        end 
                    else
                        if length~=0
                            line(app.PlotSideView, [app.D_Boosters/2+y_d app.D_Boosters/2+y_d], [L L+length],"Color","Black")
                            line(app.PlotSideView, [-app.D_Boosters/2+y_d -app.D_Boosters/2+y_d], [L L+length],"Color","Black")
                            
                            if app.InfillFactorBooster.Value ~= 1
                                line(app.PlotSideView, [app.D_Boosters/2+y_d app.D_Boosters/2*(1-app.InfillFactorBooster.Value) + y_d], [L L],"Color","Black")
                                line(app.PlotSideView, [-app.D_Boosters/2+y_d -app.D_Boosters/2*(1-app.InfillFactorBooster.Value) + y_d], [L L],"Color","Black")
                                line(app.PlotSideView, [app.D_Boosters/2+y_d app.D_Boosters/2*(1-app.InfillFactorBooster.Value) + y_d], [L+length L+length],"Color","Black")
                                line(app.PlotSideView, [-app.D_Boosters/2+y_d -app.D_Boosters/2*(1-app.InfillFactorBooster.Value) + y_d], [L+length L+length],"Color","Black")
                                line(app.PlotSideView, [-app.D_Boosters/2*(1-app.InfillFactorBooster.Value)+y_d -app.D_Boosters/2*(1-app.InfillFactorBooster.Value) + y_d], [L L+length],"Color","Black")
                                line(app.PlotSideView, [app.D_Boosters/2*(1-app.InfillFactorBooster.Value)+y_d app.D_Boosters/2*(1-app.InfillFactorBooster.Value) + y_d], [L L+length],"Color","Black")
                            else
                                line(app.PlotSideView, [app.D_Boosters/2+y_d -app.D_Boosters/2 + y_d], [L L],"Color","Black")
                                line(app.PlotSideView, [app.D_Boosters/2+y_d -app.D_Boosters/2 + y_d], [L+length L+length],"Color","Black")
                            end
                            L = L + length;
                        end
                    end
                    line(app.PlotSideView, [app.D_Boosters/2+y_d app.D_Boosters/2+y_d], [L0 L],"Color","Black")
                    line(app.PlotSideView, [-app.D_Boosters/2+y_d -app.D_Boosters/2+y_d], [L0 L],"Color","Black")
        
                    line(app.PlotSideView, [app.D_Boosters/2+y_d y_d], [L L+3*app.D_Boosters/2],"Color","Black")
                    line(app.PlotSideView, [-app.D_Boosters/2+y_d y_d], [L L+3*app.D_Boosters/2],"Color","Black")
                end
            end

            %%% Top view

            cla(app.PlotTopView)
            axis(app.PlotTopView, "equal")
            hold(app.PlotTopView, 'on')

            x=app.D_Fairing/2*cos(circle);
            y=app.D_Fairing/2*sin(circle);
            plot(app.PlotTopView,x,y,"Color","Black");
            plot(app.PlotTopView,0,0,'o',"Color","Black");

            for i=1:app.nb_Stages
                x=app.D(i)/2*cos(circle);
                y=app.D(i)/2*sin(circle);
                if app.D(i)<max(app.D(i:end)) || app.D(i) < app.D_Fairing
                    plot(app.PlotTopView,x,y,'--',"Color","Black");
                else
                    plot(app.PlotTopView,x,y,"Color","Black");
                end
            end

            if app.nb_Boosters ~= 0
                R_booster = app.D_Boosters / 2;
                
                R_masque = max([max(app.D), app.D_Fairing]) / 2; 
                
                D_centre = app.D(1)/2 + R_booster; 
                
                for i = 1:app.nb_Boosters
                    alpha = (2 * pi / app.nb_Boosters) * i;
                    x_b = D_centre * cos(alpha);
                    y_b = D_centre * sin(alpha);
                    
                    x_local = R_booster * cos(circle);
                    y_local = R_booster * sin(circle);
                    X_global = x_b + x_local;
                    Y_global = y_b + y_local;
                    
                    distance_centre = sqrt(X_global.^2 + Y_global.^2);
                    
                    est_masque = distance_centre < R_masque;
                    
                    X_hid = X_global; X_hid(~est_masque) = NaN;
                    Y_hid = Y_global; Y_hid(~est_masque) = NaN;
                    plot(app.PlotTopView, X_hid, Y_hid, "k--", "Color", "Red");
                    
                    X_vis = X_global; X_vis(est_masque) = NaN;
                    Y_vis = Y_global; Y_vis(est_masque) = NaN;
                    plot(app.PlotTopView, X_vis, Y_vis, "Color", "Red"); 

                    plot(app.PlotTopView,x_b,y_b,'o',"Color","Red");
                end
            end
            if app.Solution_found == true
                T_W_Plot(app);
            end
            State = 1;
        end

        function State = update_GUI(app)
            if app.nb_Stages == 4
                app.Stage4Panel.Visible = "on";
                if strcmp(app.Fuel(4), 'Solid')
                    app.InfillFactorStage4.Visible = "on";
                    app.SolidLabelStage4.Visible = "on";
                else
                    app.InfillFactorStage4.Visible = "off";
                    app.SolidLabelStage4.Visible = "off";
                end
            else
                app.Stage4Panel.Visible = "off";
            end

            if app.nb_Stages >= 3
                app.Stage3Panel.Visible = "on";
                if strcmp(app.Fuel(3), 'Solid')
                    app.InfillFactorStage3.Visible = "on";
                    app.SolidLabelStage3.Visible = "on";
                else
                    app.InfillFactorStage3.Visible = "off";
                    app.SolidLabelStage3.Visible = "off";
                end
            else
                app.Stage3Panel.Visible = "off";
            end

            if app.nb_Stages >= 2
                app.Stage2Panel.Visible = "on";
                if strcmp(app.Fuel(2), 'Solid')
                    app.InfillFactorStage2.Visible = "on";
                    app.SolidLabelStage2.Visible = "on";
                else
                    app.InfillFactorStage2.Visible = "off";
                    app.SolidLabelStage2.Visible = "off";
                end
            else
                app.Stage2Panel.Visible = "off";
            end

            if strcmp(app.Fuel(1), 'Solid')
                    app.InfillFactorStage1.Visible = "on";
                    app.SolidLabelStage1.Visible = "on";
            else
                    app.InfillFactorStage1.Visible = "off";
                    app.SolidLabelStage1.Visible = "off";
            end

            if app.BoostersCheckBox.Value == 1
                app.BoostersTabPanel.Visible = "on";
                if strcmp(app.BoosterFuelSwitch.Value,'Liquid fuel')
                    app.SolidFuelPanel.Visible = "off";
                    app.LiquidFuelPanel.Visible = "on";
                else
                    app.SolidFuelPanel.Visible = "on";
                    app.LiquidFuelPanel.Visible = "off";
                end
            else
                app.BoostersTabPanel.Visible = "off";
            end
            
            
            State = 1;
        end

        function State = T_W_Plot(app)
            mdot = zeros(1, app.nb_Stages);
            t = zeros(1, app.nb_Stages);
            T_total = 0;
            for i=1:app.nb_Stages
                mdot(i) = app.Thurst_Stage(i)*1000 / (9.81 * app.Isp(i));
                t(i) = app.M_ergol(i)/mdot(i);
                T_total = T_total + round(t(i),2);
            end
            
            if app.nb_Boosters == 0
                mdot_Booster = 0;
                t_booster = 0;
            else
                mdot_Booster = app.Thurst_Booster*1000 / (9.81 * app.Isp_Boosters);
                t_booster = app.Mass_Boosters/(1+app.Ms_Me_Boosters)/mdot_Booster;
            end
            
            dt = 0.01;
            
            if t(1) >= t_booster
                time = 0:dt:T_total;
            else
                time = 0:dt:round(t_booster + sum(t(2:end)), 2);
            end
            
            T_W = zeros(size(time));
            Mass = zeros(size(time));
            for k = 1:length(time)
                currentTime = time(k);
                
                if t(1) >= t_booster
                    if currentTime <= round(t_booster,2)
                        T = app.Thurst_Stage(1)*1000 + app.Thurst_Booster * app.nb_Boosters*1000;
                        M = app.M_initial(1) - mdot(1)*currentTime - app.nb_Boosters*mdot_Booster*currentTime;
                        T_W(k) = T / (M*9.81);
                        Mass(k) = M;
                        
                    elseif currentTime > round(t_booster,2) && currentTime <= round(t(1),2)
                        T = app.Thurst_Stage(1)*1000;
                        M_largage = app.M_initial(1) - mdot(1)*t_booster - app.nb_Boosters*app.Mass_Boosters; 
                        t_phase2 = currentTime - t_booster;
                        T_W(k) = T / ((M_largage - mdot(1)*t_phase2)*9.81);
                        Mass(k) = M_largage - mdot(1)*t_phase2;
                        
                    else
                        temps_accumule = round(t(1),2);
                        for j = 2:app.nb_Stages
                            if currentTime <= temps_accumule + round(t(j),2)
                                T = app.Thurst_Stage(j)*1000;
                                t_dans_etage = currentTime - temps_accumule;
                                if app.nb_Boosters == 0
                                    M_phase = app.M_initial(j) - mdot(j)*t_dans_etage;
                                else
                                    M_phase = app.M_initial(j+1) - mdot(j)*t_dans_etage;
                                end
                                T_W(k) = T / (M_phase*9.81);
                                
                                Mass(k) = M_phase;
                                break;
                            end
                            temps_accumule = temps_accumule + round(t(j),2);
                        end
                    end
                    
                else
                    if currentTime <= round(t(1),2)
                        T = (app.Thurst_Stage(1) + app.Thurst_Booster * app.nb_Boosters)*1000;
                        M = app.M_initial(1) - mdot(1)*currentTime - app.nb_Boosters*mdot_Booster*currentTime;
                        T_W(k) = T / (M*9.81);
                        Mass(k) = M;
                        
                    elseif currentTime > round(t(1),2) && currentTime <= round(t_booster,2)
                        T = (app.Thurst_Booster * app.nb_Boosters)*1000;
                        M = app.M_initial(1) - app.M_ergol(1) - app.nb_Boosters*mdot_Booster*t(1);
                        t_phase2 = currentTime - t(1);
                        T_W(k) = T / ((M - app.nb_Boosters*mdot_Booster*t_phase2)*9.81);
                        Mass(k) = M - app.nb_Boosters*mdot_Booster*t_phase2;
                        
                    else
                        temps_accumule = round(t_booster,2);
                        for j = 2:app.nb_Stages
                            if currentTime <= temps_accumule + round(t(j),2)
                                T = app.Thurst_Stage(j)*1000;
                                t_dans_etage = currentTime - temps_accumule;
                                if app.nb_Boosters == 0
                                    M_phase = app.M_initial(j) - mdot(j)*t_dans_etage;
                                else
                                    M_phase = app.M_initial(j+1) - mdot(j)*t_dans_etage;
                                end
                                T_W(k) = T / (M_phase*9.81);
                                Mass(k) = M_phase;
                                break;
                            end
                            temps_accumule = temps_accumule + round(t(j),2);
                        end
                    end
                end
            end
            
            plot(app.PlotTW, time, T_W);
            grid(app.PlotTW, 'on');

            plot(app.PlotMass, time, Mass/1000);
            grid(app.PlotMass, 'on');
            State = 1;
        end

        function State = input_dV(app)
            opts = detectImportOptions("Launcher_Data.xlsx");
            opts.VariableNamingRule = 'preserve';
            Data = readtable("Launcher_Data.xlsx", opts);
            app.dVMission.Value = Data{1, 'dV'}/1000;
            State = 0;
        end

        function State = output_Rocket_Data(app)
            dossier_app = fileparts(which(class(app))); 
            fichier = fullfile(dossier_app, "Test.xlsx");

            nettoyage = cell(20, 8); 
            nettoyage(:) = {""};
            writecell(nettoyage, fichier, 'Range', 'A2');

            T_W = zeros(1,app.nb_Stages);
            for i = 1:app.nb_Stages
                T_W(i) = app.Thurst_Stage(i)*1000/(app.M_initial(i)*9.81);
            end

            Flight_Data = [...
                app.M_initial;...
                app.M_final;...
                ];

            Stage_Data = [ ...
                app.M_ergol; ...
                app.Ms_Me; ...
                app.Isp; ...
                T_W; ...
                app.D;...
            ];

            Booster_data = [app.nb_Boosters,app.Isp_Boosters,app.Thurst_Booster,app.D_Boosters];

            writematrix(Flight_Data', fichier, 'Range', 'A2', 'WriteMode', 'inplace');
            writematrix(Stage_Data', fichier, 'Range', 'C2', 'WriteMode', 'inplace');
            writematrix(Booster_data', fichier, 'Range', 'H2', 'WriteMode', 'inplace');

            State = 0;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            update_Values(app);
        end

        % Value changed function: NbStages
        function NbStagesValueChanged(app, event)
            update_Values(app);
            update_GUI(app);
        end

        % Value changed function: BoostersCheckBox
        function BoostersCheckBoxValueChanged(app, event)
            update_Values(app);
            update_GUI(app);
        end

        % Value changed function: MPayload
        function MPayloadValueChanged(app, event)
            update_Values(app);
        end

        % Value changed function: dVMission
        function dVMissionValueChanged(app, event)
            update_Values(app);
        end

        % Value changed function: MsMeStage1
        function MsMeStage1ValueChanged(app, event)
            update_Values(app);
        end

        % Value changed function: IspStage1
        function IspStage1ValueChanged(app, event)
            update_Values(app);
        end

        % Value changed function: DStage1
        function DStage1ValueChanged(app, event)
            update_Values(app);
        end

        % Value changed function: FuelTypeStage1
        function FuelTypeStage1ValueChanged(app, event)
            update_Values(app);
            update_GUI(app);
        end

        % Value changed function: MsMeStage2
        function MsMeStage2ValueChanged(app, event)
            update_Values(app);
        end

        % Value changed function: IspStage2
        function IspStage2ValueChanged(app, event)
            update_Values(app);
        end

        % Value changed function: DStage2
        function DStage2ValueChanged(app, event)
            update_Values(app);
        end

        % Value changed function: FuelTypeStage2
        function FuelTypeStage2ValueChanged(app, event)
            update_Values(app);
            update_GUI(app);
        end

        % Value changed function: MsMeStage3
        function MsMeStage3ValueChanged(app, event)
            update_Values(app);
        end

        % Value changed function: IspStage3
        function IspStage3ValueChanged(app, event)
            update_Values(app);
        end

        % Value changed function: DStage3
        function DStage3ValueChanged(app, event)
            update_Values(app);
        end

        % Value changed function: FuelTypeStage3
        function FuelTypeStage3ValueChanged(app, event)
            update_Values(app);
            update_GUI(app);
        end

        % Value changed function: MsMeStage4
        function MsMeStage4ValueChanged(app, event)
            update_Values(app);
        end

        % Value changed function: IspStage4
        function IspStage4ValueChanged(app, event)
            update_Values(app);
        end

        % Value changed function: DStage4
        function DStage4ValueChanged(app, event)
            update_Values(app);
        end

        % Value changed function: FuelTypeStage4
        function FuelTypeStage4ValueChanged(app, event)
            update_Values(app);
            update_GUI(app);
        end

        % Value changed function: LFairing
        function LFairingValueChanged(app, event)
            update_Values(app);
        end

        % Value changed function: DFairing
        function DFairingValueChanged(app, event)
            update_Values(app);
        end

        % Value changed function: BoosterFuelSwitch
        function BoosterFuelSwitchValueChanged(app, event)
            update_Values(app);
            update_GUI(app);
        end

        % Value changed function: NbBoosters
        function NbBoostersValueChanged(app, event)
            update_Values(app);
        end

        % Value changed function: IspBoosters
        function IspBoostersValueChanged(app, event)

            update_Values(app);
        end

        % Value changed function: MsMeBoosters
        function MsMeBoostersValueChanged(app, event)
            update_Values(app);
        end

        % Value changed function: TotalMassBoosters
        function TotalMassBoostersValueChanged(app, event)
            update_Values(app);
        end

        % Value changed function: DBoosters
        function DBoostersValueChanged(app, event)
            update_Values(app);
        end

        % Value changed function: ThrustBooster
        function ThrustBoosterValueChanged(app, event)
            update_Values(app);
        end

        % Value changed function: FuelTypeBoosters
        function FuelTypeBoostersValueChanged(app, event)
            update_Values(app);
        end

        % Value changed function: InfillFactorBooster
        function InfillFactorBoosterValueChanged(app, event)
            update_Values(app);
        end

        % Value changed function: ThrustStage1
        function ThrustStage1ValueChanged(app, event)
            update_Values(app);
        end

        % Value changed function: OutputRocket
        function OutputRocketValueChanged(app, event)
            if app.Solution_found == true
                output_Rocket_Data(app);
            else
                app.ErrorOutput.Visible = "on";
            end
        end

        % Value changed function: InputdV
        function InputdVValueChanged(app, event)
            input_dV(app);
            update_Values(app);
        end

        % Value changed function: InfillFactorStage1
        function InfillFactorStage1ValueChanged(app, event)
            update_Values(app);
        end

        % Value changed function: InfillFactorStage2
        function InfillFactorStage2ValueChanged(app, event)
            update_Values(app);
        end

        % Value changed function: InfillFactorStage3
        function InfillFactorStage3ValueChanged(app, event)
            update_Values(app);
        end

        % Value changed function: InfillFactorStage4
        function InfillFactorStage4ValueChanged(app, event)
            update_Values(app);
        end

        % Value changed function: ThrustStage2
        function ThrustStage2ValueChanged(app, event)
            update_Values(app);
        end

        % Value changed function: ThrustStage3
        function ThrustStage3ValueChanged(app, event)
            update_Values(app);
        end

        % Value changed function: ThrustStage4
        function ThrustStage4ValueChanged(app, event)
            update_Values(app);
        end

        % Value changed function: AutomaticdVCalculationCheck
        function AutomaticdVCalculationCheckValueChanged(app, event)
            value = app.AutomaticdVCalculationCheck.Value;
            if value == 0
                app.dVLabel.Visible = "on";
                app.dVLabel_2.Visible = "on";
                app.dVLabel_3.Visible = "on";
                app.dVLabel_4.Visible = "on";

                app.dVPercentStage1.Visible = "on";
                app.dVPercentStage2.Visible = "on";
                app.dVPercentStage3.Visible = "on";
                app.dVPercentStage4.Visible = "on";

                if app.nb_Boosters > 0
                    app.dVPanel.Visible = "on";
                end
            else
                app.dVLabel.Visible = "off";
                app.dVLabel_2.Visible = "off";
                app.dVLabel_3.Visible = "off";
                app.dVLabel_4.Visible = "off";

                app.dVPercentStage1.Visible = "off";
                app.dVPercentStage2.Visible = "off";
                app.dVPercentStage3.Visible = "off";
                app.dVPercentStage4.Visible = "off";

                if app.nb_Boosters > 0
                    app.dVPanel.Visible = "off";
                end
            end
            update_Values(app);
        end

        % Value changed function: dVPercentStage1
        function dVPercentStage1ValueChanged(app, event)
            app.dV_Percent(1) = app.dVPercentStage1.Value;
            upscale_dVPercent(app,1);
            update_Values(app);
        end

        % Value changed function: dVPercentBooster
        function dVPercentBoosterValueChanged(app, event)
            app.dV_Percent_Booster = app.dVPercentBooster.Value;
            upscale_dVPercent(app,0);
            update_Values(app);
        end

        % Value changed function: dVPercentStage2
        function dVPercentStage2ValueChanged(app, event)
            app.dV_Percent(2) = app.dVPercentStage2.Value;
            upscale_dVPercent(app,2);
            update_Values(app);
        end

        % Value changed function: dVPercentStage3
        function dVPercentStage3ValueChanged(app, event)
            app.dV_Percent(3) = app.dVPercentStage3.Value;
            upscale_dVPercent(app,3);
            update_Values(app);
        end

        % Value changed function: dVPercentStage4
        function dVPercentStage4ValueChanged(app, event)
            app.dV_Percent(4) = app.dVPercentStage4.Value;
            upscale_dVPercent(app,4);
            update_Values(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PlotSideView = uiaxes(app.UIFigure);
            title(app.PlotSideView, 'Side View')
            xlabel(app.PlotSideView, 'r (m)')
            ylabel(app.PlotSideView, 'L (m)')
            zlabel(app.PlotSideView, 'Z')
            app.PlotTopView = uiaxes(app.UIFigure);
            title(app.PlotTopView, 'Top View')
            xlabel(app.PlotTopView, 'X (m)')
            ylabel(app.PlotTopView, 'Y (m)')
            zlabel(app.PlotTopView, 'Z')
            app.PlotTW = uiaxes(app.UIFigure);
            title(app.PlotTW, 'Thrust to Weight Ratio')
            xlabel(app.PlotTW, 't (s)')
            ylabel(app.PlotTW, 'T/W')
            zlabel(app.PlotTW, 'Z')
            app.PlotMass = uiaxes(app.UIFigure);
            title(app.PlotMass, 'Mass over Time')
            xlabel(app.PlotMass, 't (s)')
            ylabel(app.PlotMass, 'M (t)')
            zlabel(app.PlotMass, 'Z')
            app.Title = uilabel(app.UIFigure);
            app.Title.Text = 'Rocket Designer dV Calculator';
            app.MassPayloadkgSpinnerLabel = uilabel(app.UIFigure);
            app.MassPayloadkgSpinnerLabel.Text = 'Mass Payload (kg) ';
            app.MPayload = uispinner(app.UIFigure);
            app.MPayload.Step = 50;
            app.MPayload.Limits = [1 Inf];
            app.MPayload.ValueChangedFcn = createCallbackFcn(app, @MPayloadValueChanged, true);
            app.MPayload.Value = 9000;
            app.dVMissionkmsSpinnerLabel = uilabel(app.UIFigure);
            app.dVMissionkmsSpinnerLabel.Text = 'dV Mission (km/s) ';
            app.dVMission = uispinner(app.UIFigure);
            app.dVMission.Step = 0.5;
            app.dVMission.Limits = [0.5 Inf];
            app.dVMission.ValueChangedFcn = createCallbackFcn(app, @dVMissionValueChanged, true);
            app.dVMission.Value = 9.5;
            app.InputdV = uibutton(app.UIFigure, 'state');
            app.InputdV.ValueChangedFcn = createCallbackFcn(app, @InputdVValueChanged, true);
            app.InputdV.Text = 'Input dV';
            app.NumberofStagesSpinnerLabel = uilabel(app.UIFigure);
            app.NumberofStagesSpinnerLabel.Text = 'Number of Stages';
            app.NbStages = uispinner(app.UIFigure);
            app.NbStages.Limits = [1 4];
            app.NbStages.ValueChangedFcn = createCallbackFcn(app, @NbStagesValueChanged, true);
            app.NbStages.Value = 2;
            app.OutputRocket = uibutton(app.UIFigure, 'state');
            app.OutputRocket.ValueChangedFcn = createCallbackFcn(app, @OutputRocketValueChanged, true);
            app.OutputRocket.Text = 'Output Rocket Data';
            app.BoostersCheckBox = uicheckbox(app.UIFigure);
            app.BoostersCheckBox.ValueChangedFcn = createCallbackFcn(app, @BoostersCheckBoxValueChanged, true);
            app.BoostersCheckBox.Text = 'Boosters';
            app.FairingsLengthkgLabel = uilabel(app.UIFigure);
            app.FairingsLengthkgLabel.Text = 'Fairing Length (m) ';
            app.LFairing = uispinner(app.UIFigure);
            app.LFairing.Limits = [7.5 Inf];
            app.LFairing.ValueChangedFcn = createCallbackFcn(app, @LFairingValueChanged, true);
            app.LFairing.Value = 15;
            app.FairingsLengthkgLabel_2 = uilabel(app.UIFigure);
            app.FairingsLengthkgLabel_2.Text = 'Fairing Diameter(m) ';
            app.DFairing = uispinner(app.UIFigure);
            app.DFairing.Step = 0.25;
            app.DFairing.Limits = [0.1 Inf];
            app.DFairing.ValueChangedFcn = createCallbackFcn(app, @DFairingValueChanged, true);
            app.DFairing.Value = 4;
            app.Stage4Panel = uipanel(app.UIFigure);
            app.Stage4Panel.Title = 'Stage 4  ';
            app.Stage4Panel.Visible = 'off';
            app.MsMeSpinner_5Label = uilabel(app.Stage4Panel);
            app.MsMeSpinner_5Label.Text = 'Ms/Me';
            app.MsMeStage4 = uispinner(app.Stage4Panel);
            app.MsMeStage4.Step = 0.05;
            app.MsMeStage4.Limits = [0.01 1];
            app.MsMeStage4.ValueChangedFcn = createCallbackFcn(app, @MsMeStage4ValueChanged, true);
            app.MsMeStage4.Value = 0.15;
            app.IspStage4 = uispinner(app.Stage4Panel);
            app.IspStage4.Step = 10;
            app.IspStage4.Limits = [1 Inf];
            app.IspStage4.ValueChangedFcn = createCallbackFcn(app, @IspStage4ValueChanged, true);
            app.IspStage4.Value = 420;
            app.IspsSpinner_4Label = uilabel(app.Stage4Panel);
            app.IspsSpinner_4Label.Text = 'Isp (s)';
            app.DStage4 = uispinner(app.Stage4Panel);
            app.DStage4.Step = 0.25;
            app.DStage4.Limits = [0.1 Inf];
            app.DStage4.ValueChangedFcn = createCallbackFcn(app, @DStage4ValueChanged, true);
            app.DStage4.Value = 3;
            app.DiametermSpinner_5Label = uilabel(app.Stage4Panel);
            app.DiametermSpinner_5Label.Text = 'Diameter (m)';
            app.FuelDropDown_4Label = uilabel(app.Stage4Panel);
            app.FuelDropDown_4Label.Text = 'Fuel';
            app.FuelTypeStage4 = uidropdown(app.Stage4Panel);
            app.FuelTypeStage4.Items = {'LOx/LH2', 'LOx/LCh4', 'LOx/RP1', 'NTO/UDMH', 'Solid'};
            app.FuelTypeStage4.ValueChangedFcn = createCallbackFcn(app, @FuelTypeStage4ValueChanged, true);
            app.FuelTypeStage4.Value = 'LOx/LH2';
            app.DiametermSpinner_2Label_5 = uilabel(app.Stage4Panel);
            app.DiametermSpinner_2Label_5.Text = 'Thrust (kN)';
            app.ThrustStage4 = uispinner(app.Stage4Panel);
            app.ThrustStage4.Step = 100;
            app.ThrustStage4.Limits = [1 Inf];
            app.ThrustStage4.ValueChangedFcn = createCallbackFcn(app, @ThrustStage4ValueChanged, true);
            app.ThrustStage4.Value = 300;
            app.InfillFactorStage4 = uispinner(app.Stage4Panel);
            app.InfillFactorStage4.Step = 0.05;
            app.InfillFactorStage4.Limits = [0.05 1];
            app.InfillFactorStage4.ValueChangedFcn = createCallbackFcn(app, @InfillFactorStage4ValueChanged, true);
            app.InfillFactorStage4.Visible = 'off';
            app.InfillFactorStage4.Value = 1;
            app.SolidLabelStage4 = uilabel(app.Stage4Panel);
            app.SolidLabelStage4.Visible = 'off';
            app.SolidLabelStage4.Text = 'Infill Factor';
            app.dVPercentStage4 = uispinner(app.Stage4Panel);
            app.dVPercentStage4.Step = 0.1;
            app.dVPercentStage4.Limits = [0.01 1];
            app.dVPercentStage4.ValueChangedFcn = createCallbackFcn(app, @dVPercentStage4ValueChanged, true);
            app.dVPercentStage4.Visible = 'off';
            app.dVPercentStage4.Value = 1;
            app.dVLabel_4 = uilabel(app.Stage4Panel);
            app.dVLabel_4.Visible = 'off';
            app.dVLabel_4.Text = 'dV %';
            app.Stage3Panel = uipanel(app.UIFigure);
            app.Stage3Panel.Title = 'Stage 3';
            app.Stage3Panel.Visible = 'off';
            app.MsMeSpinner_4Label = uilabel(app.Stage3Panel);
            app.MsMeSpinner_4Label.Text = 'Ms/Me';
            app.MsMeStage3 = uispinner(app.Stage3Panel);
            app.MsMeStage3.Step = 0.05;
            app.MsMeStage3.Limits = [0.01 1];
            app.MsMeStage3.ValueChangedFcn = createCallbackFcn(app, @MsMeStage3ValueChanged, true);
            app.MsMeStage3.Value = 0.15;
            app.IspStage3 = uispinner(app.Stage3Panel);
            app.IspStage3.Step = 10;
            app.IspStage3.Limits = [1 Inf];
            app.IspStage3.ValueChangedFcn = createCallbackFcn(app, @IspStage3ValueChanged, true);
            app.IspStage3.Value = 420;
            app.IspsSpinner_3Label = uilabel(app.Stage3Panel);
            app.IspsSpinner_3Label.Text = 'Isp (s)';
            app.DStage3 = uispinner(app.Stage3Panel);
            app.DStage3.Step = 0.25;
            app.DStage3.Limits = [0.1 Inf];
            app.DStage3.ValueChangedFcn = createCallbackFcn(app, @DStage3ValueChanged, true);
            app.DStage3.Value = 3;
            app.DiametermSpinner_4Label = uilabel(app.Stage3Panel);
            app.DiametermSpinner_4Label.Text = 'Diameter (m)';
            app.FuelDropDown_3Label = uilabel(app.Stage3Panel);
            app.FuelDropDown_3Label.Text = 'Fuel';
            app.FuelTypeStage3 = uidropdown(app.Stage3Panel);
            app.FuelTypeStage3.Items = {'LOx/LH2', 'LOx/LCh4', 'LOx/RP1', 'NTO/UDMH', 'Solid'};
            app.FuelTypeStage3.ValueChangedFcn = createCallbackFcn(app, @FuelTypeStage3ValueChanged, true);
            app.FuelTypeStage3.Value = 'LOx/LH2';
            app.DiametermSpinner_2Label_6 = uilabel(app.Stage3Panel);
            app.DiametermSpinner_2Label_6.Text = 'Thrust (kN)';
            app.ThrustStage3 = uispinner(app.Stage3Panel);
            app.ThrustStage3.Step = 100;
            app.ThrustStage3.Limits = [1 Inf];
            app.ThrustStage3.ValueChangedFcn = createCallbackFcn(app, @ThrustStage3ValueChanged, true);
            app.ThrustStage3.Value = 400;
            app.InfillFactorStage3 = uispinner(app.Stage3Panel);
            app.InfillFactorStage3.Step = 0.05;
            app.InfillFactorStage3.Limits = [0.05 1];
            app.InfillFactorStage3.ValueChangedFcn = createCallbackFcn(app, @InfillFactorStage3ValueChanged, true);
            app.InfillFactorStage3.Visible = 'off';
            app.InfillFactorStage3.Value = 1;
            app.SolidLabelStage3 = uilabel(app.Stage3Panel);
            app.SolidLabelStage3.Visible = 'off';
            app.SolidLabelStage3.Text = 'Infill Factor';
            app.dVPercentStage3 = uispinner(app.Stage3Panel);
            app.dVPercentStage3.Step = 0.1;
            app.dVPercentStage3.Limits = [0.01 1];
            app.dVPercentStage3.ValueChangedFcn = createCallbackFcn(app, @dVPercentStage3ValueChanged, true);
            app.dVPercentStage3.Visible = 'off';
            app.dVPercentStage3.Value = 1;
            app.dVLabel_3 = uilabel(app.Stage3Panel);
            app.dVLabel_3.Visible = 'off';
            app.dVLabel_3.Text = 'dV %';
            app.Stage2Panel = uipanel(app.UIFigure);
            app.Stage2Panel.Title = 'Stage 2';
            app.MsMeSpinner_3Label = uilabel(app.Stage2Panel);
            app.MsMeSpinner_3Label.Text = 'Ms/Me';
            app.MsMeStage2 = uispinner(app.Stage2Panel);
            app.MsMeStage2.Step = 0.05;
            app.MsMeStage2.Limits = [0.01 1];
            app.MsMeStage2.ValueChangedFcn = createCallbackFcn(app, @MsMeStage2ValueChanged, true);
            app.MsMeStage2.Value = 0.15;
            app.IspStage2 = uispinner(app.Stage2Panel);
            app.IspStage2.Step = 10;
            app.IspStage2.Limits = [1 Inf];
            app.IspStage2.ValueChangedFcn = createCallbackFcn(app, @IspStage2ValueChanged, true);
            app.IspStage2.Value = 420;
            app.IspsSpinner_2Label = uilabel(app.Stage2Panel);
            app.IspsSpinner_2Label.Text = 'Isp (s)';
            app.DStage2 = uispinner(app.Stage2Panel);
            app.DStage2.Step = 0.25;
            app.DStage2.Limits = [0.1 Inf];
            app.DStage2.ValueChangedFcn = createCallbackFcn(app, @DStage2ValueChanged, true);
            app.DStage2.Value = 3.7;
            app.DiametermSpinner_3Label = uilabel(app.Stage2Panel);
            app.DiametermSpinner_3Label.Text = 'Diameter (m)';
            app.DiametermSpinner_2Label_7 = uilabel(app.Stage2Panel);
            app.DiametermSpinner_2Label_7.Text = 'Thrust (kN)';
            app.ThrustStage2 = uispinner(app.Stage2Panel);
            app.ThrustStage2.Step = 100;
            app.ThrustStage2.Limits = [1 Inf];
            app.ThrustStage2.ValueChangedFcn = createCallbackFcn(app, @ThrustStage2ValueChanged, true);
            app.ThrustStage2.Value = 800;
            app.FuelDropDownLabel_2 = uilabel(app.Stage2Panel);
            app.FuelDropDownLabel_2.Text = 'Fuel';
            app.FuelTypeStage2 = uidropdown(app.Stage2Panel);
            app.FuelTypeStage2.Items = {'LOx/LH2', 'LOx/LCh4', 'LOx/RP1', 'NTO/UDMH', 'Solid'};
            app.FuelTypeStage2.ValueChangedFcn = createCallbackFcn(app, @FuelTypeStage2ValueChanged, true);
            app.FuelTypeStage2.Value = 'LOx/RP1';
            app.InfillFactorStage2 = uispinner(app.Stage2Panel);
            app.InfillFactorStage2.Step = 0.05;
            app.InfillFactorStage2.Limits = [0.05 1];
            app.InfillFactorStage2.ValueChangedFcn = createCallbackFcn(app, @InfillFactorStage2ValueChanged, true);
            app.InfillFactorStage2.Visible = 'off';
            app.InfillFactorStage2.Value = 1;
            app.SolidLabelStage2 = uilabel(app.Stage2Panel);
            app.SolidLabelStage2.Visible = 'off';
            app.SolidLabelStage2.Text = 'Infill Factor';
            app.dVPercentStage2 = uispinner(app.Stage2Panel);
            app.dVPercentStage2.Step = 0.1;
            app.dVPercentStage2.Limits = [0.01 1];
            app.dVPercentStage2.ValueChangedFcn = createCallbackFcn(app, @dVPercentStage2ValueChanged, true);
            app.dVPercentStage2.Visible = 'off';
            app.dVPercentStage2.Value = 1;
            app.dVLabel_2 = uilabel(app.Stage2Panel);
            app.dVLabel_2.Visible = 'off';
            app.dVLabel_2.Text = 'dV %';
            app.Stage1Panel = uipanel(app.UIFigure);
            app.Stage1Panel.Title = 'Stage 1';
            app.MsMeSpinner_2Label = uilabel(app.Stage1Panel);
            app.MsMeSpinner_2Label.Text = 'Ms/Me';
            app.MsMeStage1 = uispinner(app.Stage1Panel);
            app.MsMeStage1.Step = 0.05;
            app.MsMeStage1.Limits = [0.01 1];
            app.MsMeStage1.ValueChangedFcn = createCallbackFcn(app, @MsMeStage1ValueChanged, true);
            app.MsMeStage1.Value = 0.15;
            app.IspsSpinnerLabel = uilabel(app.Stage1Panel);
            app.IspsSpinnerLabel.Text = 'Isp (s)';
            app.IspStage1 = uispinner(app.Stage1Panel);
            app.IspStage1.Step = 10;
            app.IspStage1.Limits = [1 Inf];
            app.IspStage1.ValueChangedFcn = createCallbackFcn(app, @IspStage1ValueChanged, true);
            app.IspStage1.Value = 350;
            app.DiametermSpinner_2Label_8 = uilabel(app.Stage1Panel);
            app.DiametermSpinner_2Label_8.Text = 'Diameter (m)';
            app.DStage1 = uispinner(app.Stage1Panel);
            app.DStage1.Step = 0.25;
            app.DStage1.Limits = [0.1 Inf];
            app.DStage1.ValueChangedFcn = createCallbackFcn(app, @DStage1ValueChanged, true);
            app.DStage1.Value = 3.7;
            app.FuelDropDownLabel = uilabel(app.Stage1Panel);
            app.FuelDropDownLabel.Text = 'Fuel';
            app.FuelTypeStage1 = uidropdown(app.Stage1Panel);
            app.FuelTypeStage1.Items = {'LOx/LH2', 'LOx/LCh4', 'LOx/RP1', 'NTO/UDMH', 'Solid'};
            app.FuelTypeStage1.ValueChangedFcn = createCallbackFcn(app, @FuelTypeStage1ValueChanged, true);
            app.FuelTypeStage1.Value = 'LOx/RP1';
            app.DiametermSpinner_2Label_9 = uilabel(app.Stage1Panel);
            app.DiametermSpinner_2Label_9.Text = 'Thrust (kN)';
            app.ThrustStage1 = uispinner(app.Stage1Panel);
            app.ThrustStage1.Step = 100;
            app.ThrustStage1.Limits = [1 Inf];
            app.ThrustStage1.ValueChangedFcn = createCallbackFcn(app, @ThrustStage1ValueChanged, true);
            app.ThrustStage1.Value = 2200;
            app.dVPercentStage1 = uispinner(app.Stage1Panel);
            app.dVPercentStage1.Step = 0.1;
            app.dVPercentStage1.Limits = [0.01 1];
            app.dVPercentStage1.ValueChangedFcn = createCallbackFcn(app, @dVPercentStage1ValueChanged, true);
            app.dVPercentStage1.Visible = 'off';
            app.dVPercentStage1.Value = 1;
            app.dVLabel = uilabel(app.Stage1Panel);
            app.dVLabel.Visible = 'off';
            app.dVLabel.Text = 'dV %';
            app.BoostersTabPanel = uipanel(app.UIFigure);
            app.BoostersTabPanel.Title = 'Boosters Tab';
            app.BoostersTabPanel.Visible = 'off';
            app.NumberofBoostersSpinnerLabel = uilabel(app.BoostersTabPanel);
            app.NumberofBoostersSpinnerLabel.Text = 'Number of Boosters';
            app.NbBoosters = uispinner(app.BoostersTabPanel);
            app.NbBoosters.Limits = [2 8];
            app.NbBoosters.ValueChangedFcn = createCallbackFcn(app, @NbBoostersValueChanged, true);
            app.NbBoosters.Value = 2;
            app.IspBoosterssSpinnerLabel = uilabel(app.BoostersTabPanel);
            app.IspBoosterssSpinnerLabel.Text = 'Isp Boosters (s)';
            app.IspBoosters = uispinner(app.BoostersTabPanel);
            app.IspBoosters.Step = 10;
            app.IspBoosters.Limits = [1 Inf];
            app.IspBoosters.ValueChangedFcn = createCallbackFcn(app, @IspBoostersValueChanged, true);
            app.IspBoosters.Value = 280;
            app.MsMeSpinnerLabel = uilabel(app.BoostersTabPanel);
            app.MsMeSpinnerLabel.Text = 'Ms/Me';
            app.MsMeBoosters = uispinner(app.BoostersTabPanel);
            app.MsMeBoosters.Step = 0.05;
            app.MsMeBoosters.Limits = [0.01 1];
            app.MsMeBoosters.ValueChangedFcn = createCallbackFcn(app, @MsMeBoostersValueChanged, true);
            app.MsMeBoosters.Value = 0.15;
            app.TotalMasstLabel = uilabel(app.BoostersTabPanel);
            app.TotalMasstLabel.Text = 'Booster Mass (t)';
            app.TotalMassBoosters = uispinner(app.BoostersTabPanel);
            app.TotalMassBoosters.Step = 5;
            app.TotalMassBoosters.Limits = [0.5 Inf];
            app.TotalMassBoosters.ValueChangedFcn = createCallbackFcn(app, @TotalMassBoostersValueChanged, true);
            app.TotalMassBoosters.Value = 30;
            app.DiametermSpinnerLabel = uilabel(app.BoostersTabPanel);
            app.DiametermSpinnerLabel.Text = 'Diameter (m)';
            app.DBoosters = uispinner(app.BoostersTabPanel);
            app.DBoosters.Step = 0.25;
            app.DBoosters.Limits = [0.1 Inf];
            app.DBoosters.ValueChangedFcn = createCallbackFcn(app, @DBoostersValueChanged, true);
            app.DBoosters.Value = 2;
            app.BoosterThrustkNLabel = uilabel(app.BoostersTabPanel);
            app.BoosterThrustkNLabel.Text = 'Booster Thrust (kN)';
            app.ThrustBooster = uispinner(app.BoostersTabPanel);
            app.ThrustBooster.Step = 100;
            app.ThrustBooster.Limits = [1 Inf];
            app.ThrustBooster.ValueChangedFcn = createCallbackFcn(app, @ThrustBoosterValueChanged, true);
            app.ThrustBooster.Value = 4780;
            app.BoosterFuelSwitch = uiswitch(app.BoostersTabPanel, 'slider');
            app.BoosterFuelSwitch.Items = {'Liquid fuel', 'Solid Fuel'};
            app.BoosterFuelSwitch.ValueChangedFcn = createCallbackFcn(app, @BoosterFuelSwitchValueChanged, true);
            app.BoosterFuelSwitch.Value = 'Liquid fuel';
            app.LiquidFuelPanel = uipanel(app.BoostersTabPanel);
            app.LiquidFuelPanel.Title = 'Liquid Fuel';
            app.FuelDropDown_4Label_2 = uilabel(app.LiquidFuelPanel);
            app.FuelDropDown_4Label_2.Text = 'Fuel';
            app.FuelTypeBoosters = uidropdown(app.LiquidFuelPanel);
            app.FuelTypeBoosters.Items = {'LOx/LH2', 'LOx/LCh4', 'LOx/RP1', 'NTO/UDMH'};
            app.FuelTypeBoosters.ValueChangedFcn = createCallbackFcn(app, @FuelTypeBoostersValueChanged, true);
            app.FuelTypeBoosters.Value = 'LOx/RP1';
            app.SolidFuelPanel = uipanel(app.BoostersTabPanel);
            app.SolidFuelPanel.Title = 'Solid Fuel';
            app.FiLabel = uilabel(app.SolidFuelPanel);
            app.FiLabel.Text = 'Infill Factor';
            app.InfillFactorBooster = uispinner(app.SolidFuelPanel);
            app.InfillFactorBooster.Step = 0.05;
            app.InfillFactorBooster.Limits = [0.05 1];
            app.InfillFactorBooster.ValueChangedFcn = createCallbackFcn(app, @InfillFactorBoosterValueChanged, true);
            app.InfillFactorBooster.Value = 1;
            app.dVPanel = uipanel(app.BoostersTabPanel);
            app.dVPanel.Title = 'dV %';
            app.dVPanel.Visible = 'off';
            app.dVPercentBooster = uispinner(app.dVPanel);
            app.dVPercentBooster.Step = 0.1;
            app.dVPercentBooster.Limits = [0.01 1];
            app.dVPercentBooster.ValueChangedFcn = createCallbackFcn(app, @dVPercentBoosterValueChanged, true);
            app.dVPercentBooster.Value = 0.5;
            app.ThisPercentagewillactasthedVofthefirstPhasewhilethepercentageofeachstagewillactasthedVofthenextphasesLabel = uilabel(app.dVPanel);
            app.ThisPercentagewillactasthedVofthefirstPhasewhilethepercentageofeachstagewillactasthedVofthenextphasesLabel.Text = 'This Percentage will act as the dV of the first Phase, while the percentage of each stage will act as the dV of the next phases';
            app.TotalMasstLabel_2 = uilabel(app.UIFigure);
            app.TotalMasstLabel_2.Text = 'Total Mass (t)';
            app.TotalMass = uieditfield(app.UIFigure, 'numeric');
            app.TotalMass.Editable = 'off';
            app.TotalMasstLabel_3 = uilabel(app.UIFigure);
            app.TotalMasstLabel_3.Text = 'Rocket Length (m)';
            app.TotalLength = uieditfield(app.UIFigure, 'numeric');
            app.TotalLength.Editable = 'off';
            app.ErrorGlobal = uilabel(app.UIFigure);
            app.ErrorGlobal.Visible = 'off';
            app.ErrorGlobal.Text = 'Error : No Solution found Change values';
            app.ErrorOutput = uilabel(app.UIFigure);
            app.ErrorOutput.Visible = 'off';
            app.ErrorOutput.Text = 'Error : Cannot output Data when no solution is found Change values';
            app.InfillFactorStage1 = uispinner(app.UIFigure);
            app.InfillFactorStage1.Step = 0.05;
            app.InfillFactorStage1.Limits = [0.05 1];
            app.InfillFactorStage1.ValueChangedFcn = createCallbackFcn(app, @InfillFactorStage1ValueChanged, true);
            app.InfillFactorStage1.Visible = 'off';
            app.InfillFactorStage1.Value = 1;
            app.SolidLabelStage1 = uilabel(app.UIFigure);
            app.SolidLabelStage1.Visible = 'off';
            app.SolidLabelStage1.Text = 'Infill Factor';
            app.AutomaticdVCalculationCheck = uicheckbox(app.UIFigure);
            app.AutomaticdVCalculationCheck.ValueChangedFcn = createCallbackFcn(app, @AutomaticdVCalculationCheckValueChanged, true);
            app.AutomaticdVCalculationCheck.Text = 'Automatic dV Calculation';
            app.AutomaticdVCalculationCheck.Value = true;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Rocket_Designer_Main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end