classdef Rocket_Designer_dV < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        Altitude_GEO                 matlab.ui.control.CheckBox
        UpdateGraphButton            matlab.ui.control.Button
        LoadLauncherDataButton       matlab.ui.control.Button
        Title                        matlab.ui.control.Label
        Error_SSO                    matlab.ui.control.Label
        SSO_Orbit_Check              matlab.ui.control.CheckBox
        Inclinaison                  matlab.ui.control.Spinner
        Launch_Site_Latitude         matlab.ui.control.Spinner
        Mission_Selection            matlab.ui.control.DropDown
        Moon_Altitude                matlab.ui.control.Spinner
        ApogeeAltitudekmLabel_2      matlab.ui.control.Label
        Moon_Altitude_Label          matlab.ui.control.Label
        Altitude                     matlab.ui.control.Spinner
        Apogee_Selection             matlab.ui.control.Spinner
        ApogeeAltitudekmLabel        matlab.ui.control.Label
        Apogee_Label                 matlab.ui.control.Label
        Perte_Aerodynamic_Edit       matlab.ui.control.Spinner
        InclinaisoniLabel_5          matlab.ui.control.Label
        Perte_Gravity_Edit           matlab.ui.control.Spinner
        InclinaisoniLabel_4          matlab.ui.control.Label
        Perte_Inclinaison_Edit       matlab.ui.control.Spinner
        InclinaisoniLabel_3          matlab.ui.control.Label
        InclinaisoniLabel_2          matlab.ui.control.Label
        InclinaisoniLabel            matlab.ui.control.Label
        AltitudeHkmSpinnerLabel      matlab.ui.control.Label
        Perte_Total_Edit             matlab.ui.control.NumericEditField
        Perte_Check                  matlab.ui.control.CheckBox
        TotaldVlossLabel             matlab.ui.control.Label
        dV_Total_Box                 matlab.ui.control.NumericEditField
        TotaldVofthemissionkmsLabel  matlab.ui.control.Label
        DropDownLabel                matlab.ui.control.Label
        Launch_Plot                  matlab.ui.control.UIAxes
        Orbit_Plot_Z                 matlab.ui.control.UIAxes
        Orbit_Plot_Y                 matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        R_e = 6371; % km
        R_m = 1737.4; % km
        g0 = 9.80665; %m/s²
        omega_e = 7.292115 * 10^-5; % rad/s
        Mu_e = 398600.2; % km^3/s^2
        Moon_Earth_distance = 384400; % km

        Apogee = 0; % km
        Perigee = 0; % km
        i = 0; % °

        lat = 0; % °
        
        Perte_Gravite = 0; % km/s
        Perte_Incidence = 0; % km/s
        Perte_Aero = 0; % km/s
        Perte_Total = 0; % km/s
        
        V_init = 0; % km/s
        V_final_burn1 = 0; % km/s
        dV_Manoeuvre = 0; % km/s
        dV_total = 0; % km/s

        Launcher_Data = NaN;

        h_kick = 1; % km
        theta_kick = 0; % °
        burnt = 0;
        Achieve = 0;
    end
    
    methods (Access = private)
        

        %% Loss Calculations
        function Perte_Aero = calc_Perte_Aero(app)
            if app.Perte_Check.Value == 0
                Perte_Aero = app.Perte_Aerodynamic_Edit.Value;
                app.Perte_Aero = app.Perte_Aerodynamic_Edit.Value;
            else
                Perte_Aero = -1;
            end
        end

        function Perte_Inclinaison = calc_Perte_Inclinaison(app)
            if app.Perte_Check.Value == 0
                Perte_Inclinaison = app.Perte_Inclinaison_Edit.Value;
                app.Perte_Incidence = app.Perte_Inclinaison_Edit.Value;
            else
                Perte_Inclinaison = -1;
            end
        end

        function Perte_Gravity = calc_Perte_Gravity(app)
            if app.Perte_Check.Value == 0
                Perte_Gravity = app.Perte_Aerodynamic_Edit.Value;
                app.Perte_Gravite = app.Perte_Gravity_Edit.Value;
            else
                Perte_Gravity = -1;
            end
        end

        function dYdt = rocket_dynamics(app, Y)
            x = Y(1);
            y = Y(2);
            v = Y(3);
            gamma = Y(4);
            m = Y(5);

            [rho, g] = app.calc_conditions(y / 1000);


            if app.burnt == 0
                T = app.Launcher_Data{1, 'T/M'}*app.Launcher_Data{1, 'Mi'}*app.g0;
                Isp = app.Launcher_Data{1, 'Isp'};
            else
                T = app.Launcher_Data{2, 'T/M'}*app.Launcher_Data{2, 'Mi'}*app.g0;
                Isp = app.Launcher_Data{2, 'Isp'};
            end

            if m <= app.Launcher_Data{1, 'Mf'} && app.burnt == 0
                app.burnt = 1;
                T = 0;
                mdot = 0;
            elseif m <= app.Launcher_Data{2, 'Mf'} && app.burnt == 1
                T = 0;
                mdot = 0;
            elseif app.Achieve == 0 && y>(app.Perigee-app.R_e)*1000
                app.Achieve = 1;
                mdot = 0;
            else
                mdot = T / (app.g0 * Isp);
            end      

            Cd = 0.3;
            A = pi * (3.5/2)^2;
            D = 0.5 * rho * v^2 * Cd * A;

            dxdt = v*cos(gamma);
            dydt = v*sin(gamma);
            dvdt = (T - D)/m - g*sin(gamma);
            if v < 0.1
                dgammadt = 0;
            else
                dgammadt = - (1 / v) * (g - (v^2 / (app.R_e * 1000 + y))) * cos(gamma);
            end
            dmdt = - mdot;

            dYdt = [dxdt;dydt;dvdt;dgammadt;dmdt;T];
        end

        function Pertes = simulation_pertes(app)
            Temps = 60*60; % 1h en s
            dt = 0.1; % s

            t = 0:dt:Temps;

            Y0 = [0; 0; 0; pi/2; app.Launcher_Data{1, 'Mi'}];
            Y_data = zeros(5,length(t));
            Y_data(:,1) = Y0;

            rho_vector = zeros(1,length(t));
            g_vector = zeros(1,length(t));
            T_data = zeros(1,length(t));

            separation = 0;
            kick_done = 0;
            app.theta_kick = app.trouver_kick_optimal(app.Altitude.Value);
            app.burnt = 0;
            app.Achieve = 0;
            for id = 2:length(t)
                dYdt = rocket_dynamics(app, Y_data(:,id-1));
                T_data(id) = dYdt(6);
                Y_data(:,id) = Y_data(:,id-1) + dYdt(1:5)*dt;
                if Y_data(2,id) >= app.h_kick*1000 && kick_done == 0
                    Y_data(4,id) = pi/2 - deg2rad(app.theta_kick);
                    kick_done = 1;
                end
                if app.burnt==1 && separation == 0
                    Y_data(5,id) = app.Launcher_Data{2, 'Mi'};
                    separation = 1;
                end
                [rho_vector(id), g_vector(id)] = app.calc_conditions(Y_data(2,id) / 1000);
                if (Y_data(2,id) < 0 && id > 10) || app.Achieve == 1
                    Y_data = Y_data(:, 1:id); % Coupe le tableau au point de crash
                    break;
                end
            end

            x_vector = Y_data(1, :);
            h_vector = Y_data(2, :);
            v_vector = Y_data(3, :);
            gamma_vector = Y_data(4, :);
            m_vector = Y_data(5, :);

            %dV_grav = trapz(t, g_vector .* sin(gamma_vector));
            %dV_aero = trapz(t, Drag_vector ./ mass_vector);

            %plot(app.Launch_Plot, t(1:length(m_vector)), rad2deg(gamma_vector))
            axis(app.Launch_Plot, "equal")
            plot(app.Launch_Plot, x_vector / 1000, h_vector / 1000)
        end

        function [h_max, crash] = simuler_trajectoire_brute(app)
            Temps = 60*5; % 1h en s
            dt = 0.1; % s

            t = 0:dt:Temps;

            Y0 = [0; 0; 0; pi/2; app.Launcher_Data{1, 'Mi'}];
            Y_data = zeros(5,length(t));
            Y_data(:,1) = Y0;

            rho_vector = zeros(1,length(t));
            g_vector = zeros(1,length(t));
            T_data = zeros(1,length(t));

            separation = 0;
            kick_done = 0;
            app.burnt = 0;
            for id = 2:length(t)
                dYdt = rocket_dynamics(app, Y_data(:,id-1));
                T_data(id) = dYdt(6);
                Y_data(:,id) = Y_data(:,id-1) + dYdt(1:5)*dt;
                if Y_data(2,id) >= app.h_kick*1000 && kick_done == 0
                    Y_data(4,id) = pi/2 - deg2rad(app.theta_kick);
                    kick_done = 1;
                end
                if app.burnt==1 && separation == 0
                    Y_data(5,id) = app.Launcher_Data{2, 'Mi'};
                    separation = 1;
                end
                [rho_vector(id), g_vector(id)] = app.calc_conditions(Y_data(2,id) / 1000);
                if (Y_data(2,id) < 0 && id > 10) || app.Achieve == 1
                    Y_data = Y_data(:, 1:id);
                    crash = true;
                    break;    
                end
                crash = false;
            end
            h_vector = Y_data(2, :);
            h_max = max(h_vector);
        end

        function theta_optimal = trouver_kick_optimal(app, H_cible)
            theta_min = 0.01;
            theta_max = 5.0;
            
            max_iterations = 20;
            it = 0;
            
            app.h_kick = 1.0; 
        
            while (theta_max - theta_min) > 0.05 && it < max_iterations
                it = it + 1;
                theta_test = (theta_min + theta_max) / 2;
                app.theta_kick = theta_test;
                
                [h_max, crash] = app.simuler_trajectoire_brute();
                
                if crash || h_max < (H_cible)
                    theta_max = theta_test;
                else
                    theta_min = theta_test;
                end
            end
            
            theta_optimal = (theta_min + theta_max) / 2;
        end


        function Perte_Total = calc_Perte_Total(app)
            condition = calc_Perte_Inclinaison(app);
            calc_Perte_Gravity(app);
            calc_Perte_Aero(app);

            if condition == -1
                app.simulation_pertes(app);
            end

            Perte_Total = app.Perte_Gravite + app.Perte_Incidence + app.Perte_Aero;
            app.Perte_Total = Perte_Total;
            app.Perte_Total_Edit.Value = Perte_Total;
        end
       
        function inclinaison = calc_SSO_i(app)
            if app.SSO_Orbit_Check.Value == 1
                OmegaH = 1.991*10^-7;
                J2 = 1.08*10^-3;

                a = (app.Apogee+app.Perigee)/2;
                e = (app.Apogee-app.Perigee)/(app.Apogee+app.Perigee);
                b = a*sqrt(1-e^2);
                p = b^2/a;
                inclinaison = acos(-2/3 * OmegaH/J2 *(p/app.R_e)^2*(a^3/app.Mu_e)^(1/2))*180/pi;

                app.i = inclinaison;
                app.Inclinaison.Value = inclinaison;
            end
            inclinaison = app.i;
        end

        function [rho, g] = calc_conditions(app, altitude)
            h = altitude*1000; % m
   
            % g
            G = 6.674e-11; %m^3/(kg*s^2)
            M_earth = 5.9722e24; %kg
            r_earth = app.R_e*1000; % m
            r = r_earth + h; %m
            g = G * M_earth/(r^2); %m/s^2

            % rho
            R = 8.31432; %J/mol.K
            M = 0.0289644; %kg/mol
        

            if h<11000
                Lb = -0.0065;
                hb = 0;
                rhob = 1.22500;
                T0 = 288.15;
            elseif h<20000
                Lb = 0;
                hb = 11000;
                rhob = 0.363910;
                T0 = 216.65;
            elseif h<32000
                Lb = 0.001;
                hb = 20000;
                T0 = 216.65;
                rhob = 0.088030;
            elseif h<47000
                Lb = 0.0028;
                hb = 32000;
                rhob = 0.013220;
                T0 = 228.65;
            elseif h<51000
                Lb = 0;
                hb = 47000;
                rhob = 0.001430;
                T0 = 270.65;
            elseif h<71000
                Lb = -0.0028;
                hb = 51000;
                rhob = 0.000860;
                T0 = 270.65;
            elseif h<= 80000
                Lb = -0.002;
                hb = 71000;
                rhob = 0.000064;
                T0 = 214.65;
            else
                rho = 0; 
                return;
            end
        
            Tb = T0 + Lb*(h-hb);
    
            if Lb == 0
                rho = rhob * exp(-app.g0 * M * (h-hb)/(R*Tb));
            else
                rho = rhob * (T0 / Tb)^(1 + app.g0*M/(R*Lb));
            end
        end
        %% dV Calculations
        function V_init = calc_rotation_Terre(app)
            V_init = app.R_e * cos(deg2rad(app.lat)) * app.omega_e;
            app.V_init = V_init;
        end

        function dV_Manoeuvre = calc_dV_Manoeuvre(app)
            switch app.Mission_Selection.Value
                case('Circular Orbit')
                    dV_Manoeuvre = 0;
                case('Elliptic Orbit')
                    a = (app.Apogee + app.Perigee)/2;
                    V_perigee = sqrt(app.Mu_e*(2/app.Perigee-1/a));

                    V_circ = sqrt(app.Mu_e/app.Perigee);
                    dV_Manoeuvre = abs(V_perigee - V_circ);
                case('Hohmann Transfert')
                    a = (app.Apogee + app.Perigee)/2;

                    V_perigee = sqrt(app.Mu_e*(2/app.Perigee-1/a));
                    V_circ_perigee = sqrt(app.Mu_e/app.Perigee);

                    V_apogee = sqrt(app.Mu_e*(2/app.Apogee-1/a));
                    V_circ_apogee = sqrt(app.Mu_e/app.Apogee);

                    dV_Manoeuvre = abs(V_perigee - V_circ_perigee) + abs(- V_apogee + V_circ_apogee);
                case('Mun Transfert')
                    a = (app.Apogee + app.Perigee)/2;
                    V_perigee = sqrt(app.Mu_e*(2/app.Perigee-1/a));

                    V_circ = sqrt(app.Mu_e/app.Perigee);
                    dV_Manoeuvre = V_perigee - V_circ;
            end
            app.dV_Manoeuvre = dV_Manoeuvre;
        end

        function V_final_burn1 = calc_V_final_burn1(app)
            if strcmp(app.Mission_Selection.Value, 'Circular Orbit')
                app.Apogee = app.Perigee;
            end

            V_final_burn1 = sqrt(app.Mu_e/app.Perigee);
            app.V_final_burn1 = V_final_burn1;
        end


        function dV_total = calc_dV_Total(app)
            calc_rotation_Terre(app);
            calc_Perte_Total(app);
            calc_dV_Manoeuvre(app);
            calc_V_final_burn1(app);

            V_i = [app.V_init; 0];
            V_f = [app.V_final_burn1*cos(app.i);app.V_final_burn1*sin(app.i)];

            dV_init = norm(V_f - V_i);

            dV_total = dV_init + app.dV_Manoeuvre + app.Perte_Total;
            app.dV_total = dV_total;
        end

        %% Updates

        function state = update_Values(app)
            calc_SSO_i(app);
            if strcmp(app.Mission_Selection.Value,'Mun Transfert')
                app.Apogee = app.Moon_Earth_distance + app.R_m + app.Moon_Altitude.Value;
            end
            switch app.Mission_Selection.Value
                case('Circular Orbit')
                    app.Apogee_Selection.Visible = "off";
                    app.Apogee_Label.Visible = "off";
                    app.Moon_Altitude.Visible = "off";
                    app.Moon_Altitude_Label.Visible = "off";
                    app.Altitude_GEO.Visible = "off";
                case('Elliptic Orbit')
                    app.Apogee_Selection.Visible = "on";
                    app.Apogee_Label.Visible = "on";
                    app.Apogee_Selection.Value = app.Apogee - app.R_e;
                    app.Moon_Altitude.Visible = "off";
                    app.Moon_Altitude_Label.Visible = "off";
                    app.Altitude_GEO.Visible = "on";
                case('Hohmann Transfert')
                    app.Apogee_Selection.Visible = "on";
                    app.Apogee_Label.Visible = "on";
                    app.Apogee_Selection.Value = app.Apogee - app.R_e;
                    app.Moon_Altitude.Visible = "off";
                    app.Moon_Altitude_Label.Visible = "off";
                    app.Altitude_GEO.Visible = "on";
                case('Mun Transfert')
                    app.Apogee_Selection.Visible = "off";
                    app.Apogee_Label.Visible = "off";
                    app.Moon_Altitude.Visible = "on";
                    app.Moon_Altitude_Label.Visible = "on";
                    app.Altitude_GEO.Visible = "off";
                    
            end

            if app.dV_Total_Box == 0
                app.Apogee_Selection.Visible = "off";
                app.Apogee_Label.Visible = "off";
                app.Moon_Altitude.Visible = "on";
                app.Moon_Altitude_Label.Visible = "on";
            else
                
            end

            calc_dV_Total(app);
            app.dV_Total_Box.Value = app.dV_total;

            update_Plots(app);

            state = true;
        end

        function state = update_Plots(app)
            cla(app.Orbit_Plot_Z)
            axis(app.Orbit_Plot_Z, "equal")
            hold(app.Orbit_Plot_Z, 'on')

            cla(app.Orbit_Plot_Y)
            axis(app.Orbit_Plot_Y, "equal")
            hold(app.Orbit_Plot_Y, 'on')
            O_x = 0;
            O_y = 0;

            O_x_mun = app.Moon_Earth_distance; % km

            r_m = app.R_m;
            r_e = app.R_e;
            r_a = app.Apogee;
            r_p = app.Perigee;
            a = (r_a + r_p)/2;
            e = (r_a-r_p)/(r_a+r_p);

            ang=0:0.01:2*pi; 

            % Earth Plot
            x_e=r_e*cos(ang);
            y_e=r_e*sin(ang);
            plot(app.Orbit_Plot_Z,O_x+x_e,O_y+y_e);
            plot(app.Orbit_Plot_Y,O_x+x_e,O_y+y_e);

            % Orbit Plot
            c = e*a;
            b = a*sqrt(1-e^2);
            X = a*cos(ang);
            Y = b*sin(ang)*cos(deg2rad(app.i));
            Z = b*sin(ang)*cos(deg2rad(app.i + 90));
            x = c + X; 
            x_z = c + X;
            y = Y;
            z = Z;

            distance_centre = sqrt(x.^2 + y.^2);
            idx_visible = (y >= 0) | (distance_centre >= r_e);
            distance_centre = sqrt(x.^2 + z.^2);
            idx_visible2 = (z >= 0) | (distance_centre >= r_e);

            x(~idx_visible) = NaN;
            y(~idx_visible) = NaN;

            x_z(~idx_visible2) = NaN;
            z(~idx_visible2) = NaN;
            
            if app.i>90
                plot(app.Orbit_Plot_Z,x,y)
                plot(app.Orbit_Plot_Y,x_z,-z)
            elseif app.i<0 && app.i>=-90
                    plot(app.Orbit_Plot_Z,x,y)
                    plot(app.Orbit_Plot_Y,x_z,-z)
            elseif app.i<-90
                plot(app.Orbit_Plot_Z,x,-y)
                plot(app.Orbit_Plot_Y,x_z,z)
            else
                plot(app.Orbit_Plot_Z,x,-y)
                plot(app.Orbit_Plot_Y,x_z,z)
            end

            switch app.Mission_Selection.Value
                case('Hohmann Transfert')
                    x=r_a*cos(ang);
                    x_z = x;
                    y=r_a*sin(ang)*cos(deg2rad(app.i));
                    z=r_a*sin(ang)*cos(deg2rad(app.i+90));
                    distance_centre = sqrt(x.^2 + y.^2);
                    idx_visible = (y >= 0) | (distance_centre >= r_e);
                    distance_centre = sqrt(x.^2 + z.^2);
                    idx_visible2 = (z >= 0) | (distance_centre >= r_e);
                    x(~idx_visible) = NaN;
                    y(~idx_visible) = NaN;
                    x_z(~idx_visible2) = NaN;
                    z(~idx_visible2) = NaN;
                    if app.i>90
                        plot(app.Orbit_Plot_Z,x,y)
                        plot(app.Orbit_Plot_Y,x_z,-z)
                    elseif app.i<0 && app.i>=-90
                            plot(app.Orbit_Plot_Z,x,y)
                            plot(app.Orbit_Plot_Y,x_z,-z)
                    elseif app.i<-90
                        plot(app.Orbit_Plot_Z,x,-y)
                        plot(app.Orbit_Plot_Y,x_z,z)
                    else
                        plot(app.Orbit_Plot_Z,x,-y)
                        plot(app.Orbit_Plot_Y,x_z,z)
                    end
                case('Mun Transfert')
                    x_m=r_m*cos(ang);
                    y_m=r_m*sin(ang);
                    plot(app.Orbit_Plot_Z,O_x_mun+x_m,O_y+y_m);
                    plot(app.Orbit_Plot_Y,O_x_mun+x_m,O_y+y_m);
            end


            state = true;
        end

        function state = load_Launcher_Info(app)
            opts = detectImportOptions("Launcher_Data.xlsx");
            opts.VariableNamingRule = 'preserve';
            Data = readtable("Launcher_Data.xlsx", opts);
            vars = {'Mi', 'Mf', 'Me', 'Ms/Me', 'Isp', 'T/M'};
            app.Launcher_Data = Data(:, vars);

            state = 1;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Perigee = app.Altitude.Value + app.R_e;
            app.i = app.Inclinaison.Value;
            app.lat = app.Launch_Site_Latitude.Value;

            update_Values(app);
        end

        % Value changed function: Altitude
        function AltitudeValueChanged(app, event)
            value = app.Altitude.Value;
            if app.SSO_Orbit_Check.Value==0
                
            elseif ~strcmp(app.Mission_Selection.Value, 'Circular Orbit') || (value)>5500
                app.SSO_Orbit_Check.Value = 0;
                app.Error_SSO.Visible = "on";
            else
                app.Error_SSO.Visible = "off";
                app.SSO_Orbit_Check.Value = 1;
            end
            app.Perigee = app.R_e + value;

            update_Values(app);
        end

        % Value changed function: Inclinaison
        function InclinaisonValueChanged(app, event)
            value = app.Inclinaison.Value;
            app.i = value;

            if app.SSO_Orbit_Check.Value==0
                app.Error_SSO.Visible = "off";
            end

            update_Values(app);
        end

        % Value changed function: Launch_Site_Latitude
        function Launch_Site_LatitudeValueChanged(app, event)
            value = app.Launch_Site_Latitude.Value;
            app.lat = value;

            update_Values(app);
        end

        % Value changed function: Mission_Selection
        function Mission_SelectionValueChanged(app, event)
            update_Values(app);
        end

        % Value changed function: Perte_Inclinaison_Edit
        function Perte_Inclinaison_EditValueChanged(app, event)
            update_Values(app);
        end

        % Value changed function: Perte_Aerodynamic_Edit
        function Perte_Aerodynamic_EditValueChanged(app, event)
            update_Values(app);
        end

        % Value changed function: Perte_Gravity_Edit
        function Perte_Gravity_EditValueChanged(app, event)
            update_Values(app);
        end

        % Value changed function: Perte_Check
        function Perte_CheckValueChanged(app, event)
            
            update_Values(app);
        end

        % Value changed function: Apogee_Selection
        function Apogee_SelectionValueChanged(app, event)
            value = app.Apogee_Selection.Value;
            app.Apogee = value + app.R_e;
            update_Values(app);
        end

        % Value changed function: Moon_Altitude
        function Moon_AltitudeValueChanged(app, event)
            update_Values(app);
        end

        % Value changed function: SSO_Orbit_Check
        function SSO_Orbit_CheckValueChanged(app, event)
            if app.SSO_Orbit_Check.Value==0
                
            elseif ~strcmp(app.Mission_Selection.Value, 'Circular Orbit') || (app.Perigee-app.R_e)>5500 
                app.SSO_Orbit_Check.Value = 0;
                app.Error_SSO.Visible = "on";
            else
                app.Error_SSO.Visible = "off";
                app.SSO_Orbit_Check.Value = 1;
            end
 
            update_Values(app);
        end

        % Button pushed function: LoadLauncherDataButton
        function LoadLauncherDataButtonPushed(app, event)
            load_Launcher_Info(app);
            simulation_pertes(app);
        end

        % Button pushed function: UpdateGraphButton
        function UpdateGraphButtonPushed(app, event)
            simulation_pertes(app);
        end

        % Value changed function: Altitude_GEO
        function Altitude_GEOValueChanged(app, event)
            value = app.Altitude_GEO.Value;
            if value == 1
                T_sideral = (23*60 + 56)*60 + 4; % s
                R = (app.Mu_e*(T_sideral/(2*pi))^2)^(1/3);
                app.Apogee = R;
                app.Apogee_Selection.Value = R-app.R_e;
            end
            update_Values(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Orbit_Plot_Y = uiaxes(app.UIFigure);
            title(app.Orbit_Plot_Y, 'Orbital Trajectory')
            xlabel(app.Orbit_Plot_Y, 'X (km)')
            ylabel(app.Orbit_Plot_Y, 'Z (km)')
            zlabel(app.Orbit_Plot_Y, 'Z')
            app.Orbit_Plot_Z = uiaxes(app.UIFigure);
            title(app.Orbit_Plot_Z, 'Orbital Trajectory')
            xlabel(app.Orbit_Plot_Z, 'X (km)')
            ylabel(app.Orbit_Plot_Z, 'Y (km)')
            zlabel(app.Orbit_Plot_Z, 'Z')
            app.Launch_Plot = uiaxes(app.UIFigure);
            title(app.Launch_Plot, 'Launch Trajectory')
            xlabel(app.Launch_Plot, 'X (km)')
            ylabel(app.Launch_Plot, 'H (km)')
            zlabel(app.Launch_Plot, 'Z')
            app.DropDownLabel = uilabel(app.UIFigure);
            app.DropDownLabel.Text = 'Drop Down';
            app.TotaldVofthemissionkmsLabel = uilabel(app.UIFigure);
            app.TotaldVofthemissionkmsLabel.Text = 'Total dV of the mission (km/s)';
            app.dV_Total_Box = uieditfield(app.UIFigure, 'numeric');
            app.dV_Total_Box.Editable = 'off';
            app.TotaldVlossLabel = uilabel(app.UIFigure);
            app.TotaldVlossLabel.Text = 'Total dV loss (km/s)';
            app.Perte_Check = uicheckbox(app.UIFigure);
            app.Perte_Check.ValueChangedFcn = createCallbackFcn(app, @Perte_CheckValueChanged, true);
            app.Perte_Check.Text = 'Automatic';
            app.Perte_Total_Edit = uieditfield(app.UIFigure, 'numeric');
            app.Perte_Total_Edit.Editable = 'off';
            app.AltitudeHkmSpinnerLabel = uilabel(app.UIFigure);
            app.AltitudeHkmSpinnerLabel.Text = 'Altitude H (km)';
            app.InclinaisoniLabel = uilabel(app.UIFigure);
            app.InclinaisoniLabel.Text = 'Inclinaison i (°)';
            app.InclinaisoniLabel_2 = uilabel(app.UIFigure);
            app.InclinaisoniLabel_2.Text = 'Launch Site Latitude (°)';
            app.InclinaisoniLabel_3 = uilabel(app.UIFigure);
            app.InclinaisoniLabel_3.Text = 'Inclinaison dV loss (km/s)';
            app.Perte_Inclinaison_Edit = uispinner(app.UIFigure);
            app.Perte_Inclinaison_Edit.Step = 0.1;
            app.Perte_Inclinaison_Edit.ValueChangedFcn = createCallbackFcn(app, @Perte_Inclinaison_EditValueChanged, true);
            app.InclinaisoniLabel_4 = uilabel(app.UIFigure);
            app.InclinaisoniLabel_4.Text = 'Gravity dV loss (km/s)';
            app.Perte_Gravity_Edit = uispinner(app.UIFigure);
            app.Perte_Gravity_Edit.Step = 0.1;
            app.Perte_Gravity_Edit.ValueChangedFcn = createCallbackFcn(app, @Perte_Gravity_EditValueChanged, true);
            app.InclinaisoniLabel_5 = uilabel(app.UIFigure);
            app.InclinaisoniLabel_5.Text = 'Aerodynamic dV loss (km/s)';
            app.Perte_Aerodynamic_Edit = uispinner(app.UIFigure);
            app.Perte_Aerodynamic_Edit.Step = 0.1;
            app.Perte_Aerodynamic_Edit.ValueChangedFcn = createCallbackFcn(app, @Perte_Aerodynamic_EditValueChanged, true);
            app.Apogee_Label = uilabel(app.UIFigure);
            app.Apogee_Label.Visible = 'off';
            app.Apogee_Label.Text = 'Apogee Altitude (km)';
            app.ApogeeAltitudekmLabel = uilabel(app.UIFigure);
            app.ApogeeAltitudekmLabel.Visible = 'off';
            app.ApogeeAltitudekmLabel.Text = 'Apogee Altitude (km)';
            app.Apogee_Selection = uispinner(app.UIFigure);
            app.Apogee_Selection.Step = 100;
            app.Apogee_Selection.Limits = [0 Inf];
            app.Apogee_Selection.ValueChangedFcn = createCallbackFcn(app, @Apogee_SelectionValueChanged, true);
            app.Apogee_Selection.Visible = 'off';
            app.Apogee_Selection.Value = 500;
            app.Altitude = uispinner(app.UIFigure);
            app.Altitude.Step = 100;
            app.Altitude.Limits = [0 Inf];
            app.Altitude.ValueChangedFcn = createCallbackFcn(app, @AltitudeValueChanged, true);
            app.Altitude.Value = 500;
            app.Moon_Altitude_Label = uilabel(app.UIFigure);
            app.Moon_Altitude_Label.Visible = 'off';
            app.Moon_Altitude_Label.Text = 'MoonAltitude (km)';
            app.ApogeeAltitudekmLabel_2 = uilabel(app.UIFigure);
            app.ApogeeAltitudekmLabel_2.Visible = 'off';
            app.ApogeeAltitudekmLabel_2.Text = 'Moon Altitude (km)';
            app.Moon_Altitude = uispinner(app.UIFigure);
            app.Moon_Altitude.Step = 10;
            app.Moon_Altitude.ValueChangedFcn = createCallbackFcn(app, @Moon_AltitudeValueChanged, true);
            app.Moon_Altitude.Visible = 'off';
            app.Moon_Altitude.Value = 50;
            app.Mission_Selection = uidropdown(app.UIFigure);
            app.Mission_Selection.Items = {'Circular Orbit', 'Elliptic Orbit', 'Hohmann Transfert', 'Mun Transfert'};
            app.Mission_Selection.ValueChangedFcn = createCallbackFcn(app, @Mission_SelectionValueChanged, true);
            app.Mission_Selection.Value = 'Circular Orbit';
            app.Launch_Site_Latitude = uispinner(app.UIFigure);
            app.Launch_Site_Latitude.Step = 10;
            app.Launch_Site_Latitude.ValueChangedFcn = createCallbackFcn(app, @Launch_Site_LatitudeValueChanged, true);
            app.Inclinaison = uispinner(app.UIFigure);
            app.Inclinaison.Step = 10;
            app.Inclinaison.Limits = [-180 180];
            app.Inclinaison.ValueChangedFcn = createCallbackFcn(app, @InclinaisonValueChanged, true);
            app.SSO_Orbit_Check = uicheckbox(app.UIFigure);
            app.SSO_Orbit_Check.ValueChangedFcn = createCallbackFcn(app, @SSO_Orbit_CheckValueChanged, true);
            app.SSO_Orbit_Check.Text = 'SSO Orbit';
            app.Error_SSO = uilabel(app.UIFigure);
            app.Error_SSO.Visible = 'off';
            app.Error_SSO.Text = 'Error : SSO Orbit needs to be circular and the altitude inferior to 6000km';
            app.Title = uilabel(app.UIFigure);
            app.Title.Text = 'Rocket Designer dV Calculator';
            app.LoadLauncherDataButton = uibutton(app.UIFigure, 'push');
            app.LoadLauncherDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadLauncherDataButtonPushed, true);
            app.LoadLauncherDataButton.Text = 'Load Launcher Data';
            app.UpdateGraphButton = uibutton(app.UIFigure, 'push');
            app.UpdateGraphButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateGraphButtonPushed, true);
            app.UpdateGraphButton.Text = 'Update Graph';
            app.Altitude_GEO = uicheckbox(app.UIFigure);
            app.Altitude_GEO.ValueChangedFcn = createCallbackFcn(app, @Altitude_GEOValueChanged, true);
            app.Altitude_GEO.Text = 'Altitude GEO';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Rocket_Designer_dV

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end